classdef MikIRAM_V1a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MikRIR_Plotting_Utility        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MikIRAMMeasureTab              matlab.ui.container.Tab
        AudioIntPanel                  matlab.ui.container.Panel
        MicTable                       matlab.ui.control.Table
        OutputdeviceDropDownLabel      matlab.ui.control.Label
        OutputdeviceDropDown           matlab.ui.control.DropDown
        InputdeviceDropDownLabel       matlab.ui.control.Label
        InputdeviceDropDown            matlab.ui.control.DropDown
        FindPlayrecDevButton           matlab.ui.control.Button
        Playrec_Status_Text            matlab.ui.control.TextArea
        OutputchannelDropDownLabel     matlab.ui.control.Label
        OutputchannelDropDown          matlab.ui.control.DropDown
        BufferlengthDropDownLabel      matlab.ui.control.Label
        BufferLengthFactorDropDown     matlab.ui.control.DropDown
        BufferLengthDisplay            matlab.ui.control.TextArea
        MetadataPanel                  matlab.ui.container.Panel
        RunEditFieldLabel              matlab.ui.control.Label
        RunlabelEditField              matlab.ui.control.EditField
        SourcelocEditFieldLabel        matlab.ui.control.Label
        SourcelocEditField             matlab.ui.control.EditField
        RecvrlocEditFieldLabel         matlab.ui.control.Label
        RecvrlocEditField              matlab.ui.control.EditField
        SpeakerIDEditFieldLabel        matlab.ui.control.Label
        LoudspeakerEditField           matlab.ui.control.EditField
        SourceReceiverdistanceEditFieldLabel  matlab.ui.control.Label
        SourceReceiverdistanceEditField  matlab.ui.control.NumericEditField
        mLabel                         matlab.ui.control.Label
        DateEditFieldLabel             matlab.ui.control.Label
        DateEditField                  matlab.ui.control.EditField
        ExtranotesEditFieldLabel       matlab.ui.control.Label
        ExtranotesEditField            matlab.ui.control.EditField
        RoomnameEditFieldLabel         matlab.ui.control.Label
        RoomnameEditField              matlab.ui.control.EditField
        SweepSetupPanel                matlab.ui.container.Panel
        SamplerateDropDownLabel        matlab.ui.control.Label
        SamplerateDropDown             matlab.ui.control.DropDown
        HzLabel_3                      matlab.ui.control.Label
        flowEditField_2Label           matlab.ui.control.Label
        Sweep_fLow                     matlab.ui.control.NumericEditField
        HzLabel_4                      matlab.ui.control.Label
        HzLabel_5                      matlab.ui.control.Label
        fhighEditField_2Label          matlab.ui.control.Label
        Sweep_fHigh                    matlab.ui.control.NumericEditField
        sLabel_6                       matlab.ui.control.Label
        TSweepdurLabel                 matlab.ui.control.Label
        Sweep_Tdur                     matlab.ui.control.NumericEditField
        sLabel_8                       matlab.ui.control.Label
        TPausedurLabel                 matlab.ui.control.Label
        PreMeasure_Tpause              matlab.ui.control.NumericEditField
        sLabel_9                       matlab.ui.control.Label
        TFadedurLabel                  matlab.ui.control.Label
        Sweep_Tfade                    matlab.ui.control.NumericEditField
        UseclicksyncCheckBox           matlab.ui.control.CheckBox
        ClicksyncchanDropDownLabel     matlab.ui.control.Label
        ClicksyncchanDropDown          matlab.ui.control.DropDown
        sLabel_10                      matlab.ui.control.Label
        TClickgapLabel                 matlab.ui.control.Label
        Sweep_Tclickgap                matlab.ui.control.NumericEditField
        averagesEditFieldLabel         matlab.ui.control.Label
        NSweepAveragesEditField        matlab.ui.control.NumericEditField
        sLabel_11                      matlab.ui.control.Label
        TrepgapLabel                   matlab.ui.control.Label
        Sweep_Trep_gap                 matlab.ui.control.NumericEditField
        UseKirkebyCheckBox             matlab.ui.control.CheckBox
        HzLabel_6                      matlab.ui.control.Label
        ftransitionwidthLabel          matlab.ui.control.Label
        Kirkeby_fTransition            matlab.ui.control.NumericEditField
        dBLabel                        matlab.ui.control.Label
        PassbandregularisationLabel    matlab.ui.control.Label
        Kirkeby_Passband_dB            matlab.ui.control.NumericEditField
        dBLabel_2                      matlab.ui.control.Label
        StopbandregularisationLabel    matlab.ui.control.Label
        Kirkeby_Stopband_dB            matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        dBFSLabel                      matlab.ui.control.Label
        VolumeLabel                    matlab.ui.control.Label
        Sweep_Volume                   matlab.ui.control.NumericEditField
        dBFSLabel_2                    matlab.ui.control.Label
        ClickVolLabel                  matlab.ui.control.Label
        Click_Volume                   matlab.ui.control.NumericEditField
        TRIRdurationLabel_2            matlab.ui.control.Label
        T_RIR_Duration_Main            matlab.ui.control.NumericEditField
        sLabel_13                      matlab.ui.control.Label
        DataManagePanel                matlab.ui.container.Panel
        MaindatafolderEditFieldLabel   matlab.ui.control.Label
        MaindatafolderEditField        matlab.ui.control.EditField
        sLabel_7                       matlab.ui.control.Label
        TRIRexportdurationLabel        matlab.ui.control.Label
        T_RIR_Duration_Export          matlab.ui.control.NumericEditField
        sLabel_12                      matlab.ui.control.Label
        TRIRprecutLabel                matlab.ui.control.Label
        T_RIR_PreCut_DurationBox       matlab.ui.control.NumericEditField
        ReplotRIRsButton               matlab.ui.control.Button
        ExportdatafileButton           matlab.ui.control.Button
        RetainAllDataCheckBox          matlab.ui.control.CheckBox
        muchlargerfilesLabel           matlab.ui.control.Label
        CustomExportPathCheckBox       matlab.ui.control.CheckBox
        PassdatatoMikRIRPlotterButton  matlab.ui.control.Button
        MeasAxes_Sweeps                matlab.ui.control.UIAxes
        MeasurementPanel               matlab.ui.container.Panel
        MeasureRIRButton               matlab.ui.control.Button
        MeasurementliveLampLabel       matlab.ui.control.Label
        MeasurementliveLamp            matlab.ui.control.Lamp
        PlotDiagnosticsCheckBox        matlab.ui.control.CheckBox
        DataacquiredLabel              matlab.ui.control.Label
        DataacquiredLamp               matlab.ui.control.Lamp
        MicaudiosignalplotingPanel     matlab.ui.container.Panel
        PanSwitch_3Label               matlab.ui.control.Label
        PanSwitchMics                  matlab.ui.control.Switch
        ZoomSwitch_3Label              matlab.ui.control.Label
        ZoomSwitchMics                 matlab.ui.control.Switch
        ResetviewButtonMics            matlab.ui.control.Button
        tendEditField_2Label           matlab.ui.control.Label
        tendEditField_2                matlab.ui.control.NumericEditField
        AmplEditFieldLabel             matlab.ui.control.Label
        AmplEditField                  matlab.ui.control.NumericEditField
        AmplEditField_2Label           matlab.ui.control.Label
        AmplEditField_2                matlab.ui.control.NumericEditField
        LinearLogamplCheckBox_2        matlab.ui.control.CheckBox
        LinearLogtimeCheckBox_2        matlab.ui.control.CheckBox
        tstartEditField_2Label         matlab.ui.control.Label
        tstartEditField_2              matlab.ui.control.NumericEditField
        MeasAxes_RIR                   matlab.ui.control.UIAxes
        FinalRIRplotingPanel           matlab.ui.container.Panel
        PanSwitch_3Label_2             matlab.ui.control.Label
        PanSwitchRIRsMeas              matlab.ui.control.Switch
        ZoomSwitch_3Label_2            matlab.ui.control.Label
        ZoomSwitchRIRsMeas             matlab.ui.control.Switch
        ResetviewButtonRIRMeas         matlab.ui.control.Button
        tendEditField_3Label           matlab.ui.control.Label
        tendEditField_RIR              matlab.ui.control.NumericEditField
        AmplEditField_3Label           matlab.ui.control.Label
        AmplEditFieldHigh_RIR          matlab.ui.control.NumericEditField
        AmplEditField_4Label           matlab.ui.control.Label
        AmplEditFieldLow_RIR           matlab.ui.control.NumericEditField
        LinearLogamplCheckBox_3        matlab.ui.control.CheckBox
        LinearLogtimeCheckBox_3        matlab.ui.control.CheckBox
        tstartEditField_3Label         matlab.ui.control.Label
        tstartEditField_RIR            matlab.ui.control.NumericEditField
        MeasAxes_RIR_RAW               matlab.ui.control.UIAxes
        RawRIRplotingPanel             matlab.ui.container.Panel
        PanSwitch_3Label_3             matlab.ui.control.Label
        PanSwitchRIRsMeas_RAW          matlab.ui.control.Switch
        ZoomSwitch_3Label_3            matlab.ui.control.Label
        ZoomSwitchRIRsMeas_RAW         matlab.ui.control.Switch
        ResetviewButtonRIRMeas_RAW     matlab.ui.control.Button
        tendEditField_4Label           matlab.ui.control.Label
        tendEditField_RIR_RAW          matlab.ui.control.NumericEditField
        AmplEditField_5Label           matlab.ui.control.Label
        AmplEditFieldHigh_RIR_RAW      matlab.ui.control.NumericEditField
        AmplEditField_6Label           matlab.ui.control.Label
        AmplEditFieldLow_RIR_RAW       matlab.ui.control.NumericEditField
        LinearLogamplCheckBox_RIR_RAW  matlab.ui.control.CheckBox
        LinearLogtimeCheckBox_RIR_RAW  matlab.ui.control.CheckBox
        tstartEditField_4Label         matlab.ui.control.Label
        tstartEditField_RIR_RAW        matlab.ui.control.NumericEditField
        MikIRAMPlotterTab              matlab.ui.container.Tab
        Axes_IR                        matlab.ui.control.UIAxes
        RIRPlotControlsPanel           matlab.ui.container.Panel
        PanSwitchLabel                 matlab.ui.control.Label
        PanSwitch                      matlab.ui.control.Switch
        ZoomSwitchLabel                matlab.ui.control.Label
        ZoomSwitch                     matlab.ui.control.Switch
        ResetviewButton                matlab.ui.control.Button
        tstartEditFieldLabel           matlab.ui.control.Label
        tstartEditField                matlab.ui.control.NumericEditField
        tendEditFieldLabel             matlab.ui.control.Label
        tendEditField                  matlab.ui.control.NumericEditField
        AmplhighEditFieldLabel         matlab.ui.control.Label
        AmplhighEditField              matlab.ui.control.NumericEditField
        AmpllowEditFieldLabel          matlab.ui.control.Label
        AmpllowEditField               matlab.ui.control.NumericEditField
        sLabel                         matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        LinearLogamplCheckBox          matlab.ui.control.CheckBox
        LeaveAxes_RIRCheckBox          matlab.ui.control.CheckBox
        LinearLogtimeCheckBox          matlab.ui.control.CheckBox
        NormaliseRIRCheckBox           matlab.ui.control.CheckBox
        TabFiles                       matlab.ui.control.Table
        Axes_FR                        matlab.ui.control.UIAxes
        RFRPlotControlsPanel           matlab.ui.container.Panel
        PanSwitch_2Label               matlab.ui.control.Label
        PanSwitch_2                    matlab.ui.control.Switch
        ZoomSwitch_2Label              matlab.ui.control.Label
        ZoomSwitch_2                   matlab.ui.control.Switch
        ResetviewButton_2              matlab.ui.control.Button
        flowEditFieldLabel             matlab.ui.control.Label
        flowEditField                  matlab.ui.control.NumericEditField
        fhighEditFieldLabel            matlab.ui.control.Label
        fhighEditField                 matlab.ui.control.NumericEditField
        HzLabel                        matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        dBhighEditFieldLabel           matlab.ui.control.Label
        dBhighEditField                matlab.ui.control.NumericEditField
        dBlowEditFieldLabel            matlab.ui.control.Label
        dBlowEditField                 matlab.ui.control.NumericEditField
        LinearLogfreqCheckBox          matlab.ui.control.CheckBox
        LeaveAxes_RFRCheckBox          matlab.ui.control.CheckBox
        LinearLogmagCheckBox           matlab.ui.control.CheckBox
        NormaliseRFRCheckBox           matlab.ui.control.CheckBox
        LoadMikRIRdataMATButton        matlab.ui.control.Button
        PlotdataButton                 matlab.ui.control.Button
        DeselectallButton              matlab.ui.control.Button
        SelectallButton                matlab.ui.control.Button
        AudioPlaybackPanel             matlab.ui.container.Panel
        PlayRIRaudioDropDownLabel      matlab.ui.control.Label
        PlayRIRaudioDropDown           matlab.ui.control.DropDown
        PlayButton                     matlab.ui.control.Button
        sLabel_3                       matlab.ui.control.Label
        PlaydurationEditFieldLabel     matlab.ui.control.Label
        PlaydurationEditField          matlab.ui.control.NumericEditField
        AudioplayingLabel              matlab.ui.control.Label
        AudioplayingLamp               matlab.ui.control.Lamp
        RIRExportPanel                 matlab.ui.container.Panel
        FsNoticeText                   matlab.ui.control.Label
        StatusTextAreaLabel            matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        ExportWAVButton                matlab.ui.control.Button
        NormEachCurveCheckBox          matlab.ui.control.CheckBox
        NormPeakCurveCheckBox          matlab.ui.control.CheckBox
        sLabel_4                       matlab.ui.control.Label
        TruncateIRCheckBox             matlab.ui.control.CheckBox
        sLabel_5                       matlab.ui.control.Label
        RIRdurationEditFieldLabel      matlab.ui.control.Label
        RIRdurationEditField           matlab.ui.control.NumericEditField
        RIRTstartEditFieldLabel        matlab.ui.control.Label
        RIRTstartEditField             matlab.ui.control.NumericEditField
        DataLoadingSwitch              matlab.ui.control.ToggleSwitch
        MikIRAMAnalysisTab             matlab.ui.container.Tab
        FeaturenotyetimplementedLabel_2  matlab.ui.control.Label
        DetailedanalysisofasingleRIRLabel  matlab.ui.control.Label
        MeasAxes_CalibCurves           matlab.ui.control.UIAxes
        CalibrationsetupoptionalnotyetimplementedPanel  matlab.ui.container.Panel
        LoadinterfacecalfileButton     matlab.ui.control.Button
        CalibrationfileinfoTextAreaLabel  matlab.ui.control.Label
        CalibrationfileinfoTextArea    matlab.ui.control.TextArea
        LoadspeakermiccalfileButton    matlab.ui.control.Button
        CalibrationfileinfoTextArea_2  matlab.ui.control.TextArea
        WAVFileAnalysisTab             matlab.ui.container.Tab
        LoadRIRWAVfileButton           matlab.ui.control.Button
        FeaturenotyetimplementedLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainDataStruct % Description
        bigCell % Description
        plotList % Description
        dataInfo % Description
        deviceStruct % Description
    end
    
    methods (Access = private)
        function plottingFn(app)
            %----------------------------------------------------%
            % This makes the IR/FR plots in the MikRIR Plotter tab
            %----------------------------------------------------%
            
            %assignin('base', 'tab', app.TabFiles.Data);
            %assignin('base', 'mainDataStruct', app.mainDataStruct);
            
            % Get overall list of files in complete dataset
            fileList          = cell2mat(app.TabFiles.Data(:,1));
            app.plotList.list = cell2mat(app.TabFiles.Data(:,8));
            
            % Work out which files the user wants to plot, then pick them out
            [list,~]       = find(cell2mat(app.TabFiles.Data(:,8)));
            N_plotsLive    = size(list,1);
            thisIR_CellRay = app.bigCell.data(list,1);
            thisFR_CellRay = app.bigCell.data(list,2);
            
            %assignin('base', 'thisIR_CellRay', thisIR_CellRay);
            %assignin('base', 'thisFR_CellRay', thisFR_CellRay);
            
            % Find max/min values in assembled IRs/FRs for plotting
            maxIR_Vals = cell2mat(cellfun(@max, thisIR_CellRay, 'Uni',0));
            minIR_Vals = cell2mat(cellfun(@min, thisIR_CellRay, 'Uni',0));
            maxFR_Vals = abs(cell2mat(cellfun(@max, thisFR_CellRay, 'Uni',0)));
            minFR_Vals = abs(cell2mat(cellfun(@min, thisFR_CellRay, 'Uni',0)));
            
            % Pick out peak MAGNITUDE values
            app.dataInfo.maxIR_Vals2 = max(abs(maxIR_Vals),abs(minIR_Vals));
            app.dataInfo.maxFR_Vals2 = max(abs(maxFR_Vals),abs(minFR_Vals));
            app.dataInfo.maxMax_IR   = max(app.dataInfo.maxIR_Vals2);
            app.dataInfo.maxMax_FR   = max(app.dataInfo.maxFR_Vals2);
            %assignin('base', 'maxIR_Vals2', maxIR_Vals);
            %assignin('base', 'maxFR_Vals2', maxFR_Vals);
            
            % Loop through chosen files and normalise if required
            % Could make faster using some kind of 'cellfun'
            switch app.NormaliseRIRCheckBox.Value
                case 0
                    for nLoop=1:N_plotsLive
                        thisIR_CellRay{nLoop} = thisIR_CellRay{nLoop}/app.dataInfo.maxMax_IR;
                    end
                case 1
                    for nLoop=1:N_plotsLive
                        thisIR_CellRay{nLoop} = thisIR_CellRay{nLoop}/app.dataInfo.maxIR_Vals2(nLoop);
                    end
            end
            switch app.NormaliseRFRCheckBox.Value
                case 0
                    for nLoop=1:N_plotsLive
                        thisFR_CellRay{nLoop} = thisFR_CellRay{nLoop}/app.dataInfo.maxMax_FR;
                    end
                case 1
                    for nLoop=1:N_plotsLive
                        thisFR_CellRay{nLoop} = thisFR_CellRay{nLoop}/app.dataInfo.maxFR_Vals2(nLoop);
                    end
            end
            
            % Main plotting loop
            hold(app.Axes_IR,'off')
            hold(app.Axes_FR,'off')
            count = 0;
            for nPlot = 1:N_plotsLive
                count = count+1;
                thisDataFile = fileList(list(nPlot));
                plottedIndices{count} = ['Curve ' num2str(list(nPlot))];
                if count>1
                    hold(app.Axes_IR,'on')
                    hold(app.Axes_FR,'on')
                end
                
                % Plot the RIR data line by line, using appropriate scaling
                this_tVec = app.mainDataStruct(thisDataFile).data.tVec;
                switch app.LinearLogamplCheckBox.Value
                    case 0
                        this_IR_data = thisIR_CellRay{nPlot,1};
                        pl_IR(count) = plot(app.Axes_IR,this_tVec,this_IR_data);
                    case 1
                        this_IR_data = 20*log10(abs(thisIR_CellRay{nPlot,1}));
                        pl_IR(count) = plot(app.Axes_IR,this_tVec,this_IR_data);
                end
                
                % Plot the RFR data line by line, using appropriate scaling
                this_fVec = app.mainDataStruct(thisDataFile).data.fVec;
                switch app.LinearLogmagCheckBox.Value
                    case 0
                        this_FR_data = abs(thisFR_CellRay{nPlot,1});
                        pl_FR(count) = plot(app.Axes_FR,this_fVec,this_FR_data);
                    case 1
                        this_FR_data = 20*log10(abs(thisFR_CellRay{nPlot,1}));
                        pl_FR(count) = plot(app.Axes_FR,this_fVec,this_FR_data);
                end
            end
            
            % Set time axis to be linear or log as required,
            switch app.LinearLogtimeCheckBox.Value
                case 0
                    app.Axes_IR.XScale = 'linear';
                case 1
                    app.Axes_IR.XScale = 'log';
            end
            
            % Set IR ampl/mag axis label as required (linear or dB)
            switch app.LinearLogamplCheckBox.Value
                case 0
                    app.Axes_IR.YLabel.String = 'Amplitude (+/- 1)';
                case 1
                    app.Axes_IR.YLabel.String = 'Magnitude (dB)';
            end
            
            % Set freq axis to be linear or log as required
            switch app.LinearLogfreqCheckBox.Value
                case 0
                    app.Axes_FR.XScale = 'linear';
                case 1
                    app.Axes_FR.XScale = 'log';
            end
            
            % Set FR mag axis label as required (linear or dB)
            switch app.LinearLogmagCheckBox.Value
                case 0
                    app.Axes_FR.YLabel.String = 'Magnitude (0:1)';
                case 1
                    app.Axes_FR.YLabel.String = 'Magnitude (dB)';
            end
            
            % Don't try to add a legend if no plots have been created
            if count>0
                l1 = legend(app.Axes_IR,plottedIndices);
                l2 = legend(app.Axes_FR,plottedIndices);
            end
            
            % If no curves plotting, empty figure to emphasise the point!
            if count==0
                plot(app.Axes_IR,0,0)
                plot(app.Axes_FR,0,0)
            end
            
            % Call the callbacks that set axes limits (once for each plot)
            % Whether or not anything is done depends on checkboxes (determined in callbacks)
            RIR_setAxes(app);
            RFR_setAxes(app);
        end
        
        function plottingFn_Meas(app)
            % Write data into workspace for checking
            %assignin('base', 'app', app);
            %assignin('base', 'RIR_metadata', RIR_metadata);
            %assignin('base', 'RIR_data', RIR_data);
            
            %-----------------------------------------------------------%
            % This makes the basic mic input plots in the MikRIR Meas tab
            %-----------------------------------------------------------%
            N_plots     = size(app.mainDataStruct(1).data.RIR_data.audioData,2);
            %this_tVec   = app.mainDataStruct(1).data.RIR_data.tVec;
            
            % Main plotting loop for microphone signals
            this_tVec   = [0:app.mainDataStruct(1).data.RIR_params.Ts:app.mainDataStruct(1).data.RIR_params.Ts*(length(app.mainDataStruct(1).data.RIR_data.audioData))-app.mainDataStruct(1).data.RIR_params.Ts]';
            hold(app.MeasAxes_Sweeps,'off')
            count = 0;
            for nPlot = 1:N_plots
                count = count+1;
                %thisDataFile = fileList(list(nPlot));
                plottedIndices{count} = ['Channel ' num2str(nPlot)];
                if count>1
                    hold(app.MeasAxes_Sweeps,'on')
                end
                
                % Plot the raw microphone data line by line, using appropriate scaling
                switch app.LinearLogamplCheckBox_2.Value
                    case 0
                        this_Mic_data = app.mainDataStruct(1).data.RIR_data.audioData(:,nPlot);
                        pl_mic(count) = plot(app.MeasAxes_Sweeps,this_tVec,this_Mic_data);
                    case 1
                        this_Mic_data = 20*log10(abs(app.mainDataStruct(1).data.RIR_data.audioData(:,nPlot)));
                        pl_mic(count) = plot(app.MeasAxes_Sweeps,this_tVec,this_Mic_data);
                end
            end
            
            % Finally, add a legend to label things
            l1 = legend(app.MeasAxes_Sweeps,plottedIndices);


            % Main plotting loop for RAW RIR signals
            % First find peak signal value over all channels recorded (for normalisation)
            %peakVal = max(max(app.mainDataStruct(1).data.RIR_data.IR_whole));
            %app.mainDataStruct(1).data.RIR_data.IR_whole = app.mainDataStruct(1).data.RIR_data.IR_whole/peakVal;
            %app.mainDataStruct(1).data.RIR_data.IR_whole
            
            this_tVec_RAW = [0:app.mainDataStruct(1).data.RIR_params.Ts:app.mainDataStruct(1).data.RIR_params.Ts*(length(app.mainDataStruct(1).data.RIR_data.IR_whole))-app.mainDataStruct(1).data.RIR_params.Ts]';
            hold(app.MeasAxes_RIR_RAW,'off')
            count = 0;
            for nPlot = 1:N_plots
                count = count+1;                
                if count>1
                    hold(app.MeasAxes_RIR_RAW,'on')
                end
                
                % Plot the raw microphone data line by line, using appropriate scaling                
                switch app.LinearLogamplCheckBox_RIR_RAW.Value
                    case 0
                        this_RIR_RAW_data = app.mainDataStruct(1).data.RIR_data.IR_whole(:,nPlot);
                        pl_RIR_RAW(count) = plot(app.MeasAxes_RIR_RAW,this_tVec_RAW,this_RIR_RAW_data);
                    case 1
                        this_RIR_RAW_data = 20*log10(abs(app.mainDataStruct(1).data.RIR_data.IR_whole(:,nPlot)));
                        pl_RIR_RAW(count) = plot(app.MeasAxes_RIR_RAW,this_tVec_RAW,this_RIR_RAW_data);
                end                                
            end
            
            % Now add in the labellings for start/end of cropped RIRs
            hold(app.MeasAxes_RIR_RAW,'on')
            start1 = app.mainDataStruct(1).data.RIR_data.IR_winRange(1);
            end1   = app.mainDataStruct(1).data.RIR_data.IR_winRange(end);
            pl_start = plot(app.MeasAxes_RIR_RAW,[this_tVec_RAW(start1),this_tVec_RAW(start1)],[-1,1],'--g');
            pl_end   = plot(app.MeasAxes_RIR_RAW,[this_tVec_RAW(end1),this_tVec_RAW(end1)],[-1,1],'--r');
            
            % Finally, add a legend to label things
            plottedIndices2 = plottedIndices;
            plottedIndices2{count+1} = 'Start';
            plottedIndices2{count+2} = 'End';            
            l2 = legend(app.MeasAxes_RIR_RAW,plottedIndices2);


            % Main plotting loop for FINAL RIR signals
            this_tVec_RIR = [0:app.mainDataStruct(1).data.RIR_params.Ts:app.mainDataStruct(1).data.RIR_params.Ts*(length(app.mainDataStruct(1).data.RIR_data.IR))-app.mainDataStruct(1).data.RIR_params.Ts]';
            hold(app.MeasAxes_RIR,'off')
            count = 0;
            for nPlot = 1:N_plots
                count = count+1;                
                if count>1
                    hold(app.MeasAxes_RIR,'on')
                end
                
                % Plot the raw microphone data line by line, using appropriate scaling
                switch app.LinearLogamplCheckBox_3.Value
                    case 0
                        this_RIR_RAW_data = app.mainDataStruct(1).data.RIR_data.IR(:,nPlot);
                        pl_RIR_RAW(count) = plot(app.MeasAxes_RIR,this_tVec_RIR,this_RIR_RAW_data);
                    case 1
                        this_RIR_RAW_data = 20*log10(abs(app.mainDataStruct(1).data.RIR_data.IR(:,nPlot)));
                        pl_RIR_RAW(count) = plot(app.MeasAxes_RIR,this_tVec_RIR,this_RIR_RAW_data);
                end                                
            end                        
            
            % Finally, add a legend to label things
            l3 = legend(app.MeasAxes_RIR,plottedIndices);


            % Call the callbacks that set axes limits (once for each plot)
            % Whether or not anything is done depends on checkboxes (determined in callbacks)
            Mics_setAxes(app);
            Mics_setAxes_RIR_RAW(app);
            Mics_setAxes_RIR_Final(app);
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the subfolder 'Code' (for this session only) so that we can make use of
            % the functions inside it
            addpath('Code_MikIRAM');
            
            % Set up the screen size for current monitor
            %screensize = get(groot,'Screensize')
            %app.MikRIR_Plotting_Utility.OuterPosition
            %app.MikRIR_Plotting_Utility.Position = screensize;
            %app.MikRIR_Plotting_Utility.Resize
            
            
            % Set the plotting tab operation to 'new data', for now.
            % This will switch over if user click button to enable external data load
            app.LoadMikRIRdataMATButton.Enable = 'Off';
            
            % Add today's date to the relevant field, in case metadata will be stored
            q=fix(clock);qq = [num2str(q(1)),'/',num2str(q(2)),'/',num2str(q(3))];
            app.DateEditField.Value = qq;
            
            % Set up the mic naming table
            chanLabel = num2cell([1:8]');
            micName   = {'Mic_1';'Not used';'Not used';'Not used';'Not used';'Not used';'Not used';'Not used'};
            plotFlag  = num2cell([true(1),false(1),false(1),false(1),false(1),false(1),false(1),false(1)]');
            micTable  = [chanLabel,micName,plotFlag];
            app.MicTable.Data = micTable;
            
            % And the sample rate drop down
            app.SamplerateDropDown.Items     = {'44100';'48000'}';
            app.SamplerateDropDown.ItemsData = [44100,48000]';
            
            % Set the initial plotting length for the raw measured RIR plot to twice the default sweep length            
            app.tendEditField_RIR_RAW.Value = app.Sweep_Tdur.Value*2 + app.T_RIR_Duration_Main.Value;
            
            % Manually set a bunch of rubbish that seems to be turned 'manual' for no reason
            % I can work out, other than a bug in App Designer
            app.Axes_IR.DataAspectRatioMode     = 'auto';
            app.Axes_IR.PlotBoxAspectRatioMode  = 'auto';
            app.Axes_IR.XColorMode              = 'auto';
            app.Axes_IR.YColorMode              = 'auto';
            app.Axes_IR.ZColorMode              = 'auto';
            app.Axes_IR.CLimMode                = 'auto';
            app.Axes_IR.XLimMode                = 'auto';
            app.Axes_IR.YLimMode                = 'auto';
            app.Axes_IR.ZLimMode                = 'auto';
            app.Axes_IR.GridColorMode           = 'auto';
            app.Axes_IR.MinorGridAlphaMode      = 'auto';
            app.Axes_IR.MinorGridColorMode      = 'auto';
            app.Axes_IR.TickDirMode             = 'auto';
            app.Axes_IR.XTickLabelMode          = 'auto';
            app.Axes_IR.YTickLabelMode          = 'auto';
            app.Axes_IR.ZTickLabelMode          = 'auto';
            app.Axes_IR.XTickMode               = 'auto';
            app.Axes_IR.YTickMode               = 'auto';
            app.Axes_IR.ZTickMode               = 'auto';
            app.Axes_IR.CameraPositionMode      = 'auto';
            app.Axes_IR.CameraTargetMode        = 'auto';
            app.Axes_IR.CameraUpVectorMode      = 'auto';
            app.Axes_IR.CameraViewAngleMode     = 'auto';
            
            app.Axes_FR.DataAspectRatioMode     = 'auto';
            app.Axes_FR.PlotBoxAspectRatioMode  = 'auto';
            app.Axes_FR.XColorMode              = 'auto';
            app.Axes_FR.YColorMode              = 'auto';
            app.Axes_FR.ZColorMode              = 'auto';
            app.Axes_FR.CLimMode                = 'auto';
            app.Axes_FR.XLimMode                = 'auto';
            app.Axes_FR.YLimMode                = 'auto';
            app.Axes_FR.ZLimMode                = 'auto';
            app.Axes_FR.GridColorMode           = 'auto';
            app.Axes_FR.MinorGridAlphaMode      = 'auto';
            app.Axes_FR.MinorGridColorMode      = 'auto';
            app.Axes_FR.TickDirMode             = 'auto';
            app.Axes_FR.XTickLabelMode          = 'auto';
            app.Axes_FR.YTickLabelMode          = 'auto';
            app.Axes_FR.ZTickLabelMode          = 'auto';
            app.Axes_FR.XTickMode               = 'auto';
            app.Axes_FR.YTickMode               = 'auto';
            app.Axes_FR.ZTickMode               = 'auto';
            app.Axes_FR.CameraPositionMode      = 'auto';
            app.Axes_FR.CameraTargetMode        = 'auto';
            app.Axes_FR.CameraUpVectorMode      = 'auto';
            app.Axes_FR.CameraViewAngleMode     = 'auto';
            
            app.MeasAxes_Sweeps.DataAspectRatioMode     = 'auto';
            app.MeasAxes_Sweeps.PlotBoxAspectRatioMode  = 'auto';
            app.MeasAxes_Sweeps.XColorMode              = 'auto';
            app.MeasAxes_Sweeps.YColorMode              = 'auto';
            app.MeasAxes_Sweeps.ZColorMode              = 'auto';
            app.MeasAxes_Sweeps.CLimMode                = 'auto';
            app.MeasAxes_Sweeps.XLimMode                = 'auto';
            app.MeasAxes_Sweeps.YLimMode                = 'auto';
            app.MeasAxes_Sweeps.ZLimMode                = 'auto';
            app.MeasAxes_Sweeps.GridColorMode           = 'auto';
            app.MeasAxes_Sweeps.MinorGridAlphaMode      = 'auto';
            app.MeasAxes_Sweeps.MinorGridColorMode      = 'auto';
            app.MeasAxes_Sweeps.TickDirMode             = 'auto';
            app.MeasAxes_Sweeps.XTickLabelMode          = 'auto';
            app.MeasAxes_Sweeps.YTickLabelMode          = 'auto';
            app.MeasAxes_Sweeps.ZTickLabelMode          = 'auto';
            app.MeasAxes_Sweeps.XTickMode               = 'auto';
            app.MeasAxes_Sweeps.YTickMode               = 'auto';
            app.MeasAxes_Sweeps.ZTickMode               = 'auto';
            app.MeasAxes_Sweeps.CameraPositionMode      = 'auto';
            app.MeasAxes_Sweeps.CameraTargetMode        = 'auto';
            app.MeasAxes_Sweeps.CameraUpVectorMode      = 'auto';
            app.MeasAxes_Sweeps.CameraViewAngleMode     = 'auto';
            
            app.MeasAxes_CalibCurves.DataAspectRatioMode     = 'auto';
            app.MeasAxes_CalibCurves.PlotBoxAspectRatioMode  = 'auto';
            app.MeasAxes_CalibCurves.XColorMode              = 'auto';
            app.MeasAxes_CalibCurves.YColorMode              = 'auto';
            app.MeasAxes_CalibCurves.ZColorMode              = 'auto';
            app.MeasAxes_CalibCurves.CLimMode                = 'auto';
            app.MeasAxes_CalibCurves.XLimMode                = 'auto';
            app.MeasAxes_CalibCurves.YLimMode                = 'auto';
            app.MeasAxes_CalibCurves.ZLimMode                = 'auto';
            app.MeasAxes_CalibCurves.GridColorMode           = 'auto';
            app.MeasAxes_CalibCurves.MinorGridAlphaMode      = 'auto';
            app.MeasAxes_CalibCurves.MinorGridColorMode      = 'auto';
            app.MeasAxes_CalibCurves.TickDirMode             = 'auto';
            app.MeasAxes_CalibCurves.XTickLabelMode          = 'auto';
            app.MeasAxes_CalibCurves.YTickLabelMode          = 'auto';
            app.MeasAxes_CalibCurves.ZTickLabelMode          = 'auto';
            app.MeasAxes_CalibCurves.XTickMode               = 'auto';
            app.MeasAxes_CalibCurves.YTickMode               = 'auto';
            app.MeasAxes_CalibCurves.ZTickMode               = 'auto';
            app.MeasAxes_CalibCurves.CameraPositionMode      = 'auto';
            app.MeasAxes_CalibCurves.CameraTargetMode        = 'auto';
            app.MeasAxes_CalibCurves.CameraUpVectorMode      = 'auto';
            app.MeasAxes_CalibCurves.CameraViewAngleMode     = 'auto';
        end

        % Button pushed function: LoadMikRIRdataMATButton
        function LoadMikRIRdataMATButtonPushed(app, event)
            % First of all, set 'data acquired' lamp off, so we know the current data
            % buffer contains 'old' data
            %app.DataacquiredLamp.Enable = 'Off';            
            
            % Pull in data from either a saved datafile, or passed in from measurement tab
            switch str2double(app.DataLoadingSwitch.Value)
                case 1
                    clear app.mainDataStruct
                    [filename, pathname] = uigetfile('*.mat','MultiSelect', 'on');
            end
            
            % Check whether more than 1 file has been loaded
            switch exist('filename')
                case 1
                    checkCell = iscell(filename);
                otherwise
                    checkCell = false;
            end
            switch checkCell
                case 0
                    count2  = 0;
                    switch str2double(app.DataLoadingSwitch.Value)
                        case 1
                            app.mainDataStruct(1).data = load([pathname,filename]);
                            app.DataacquiredLamp.Enable = 'Off';
                            % Check if old data format is being loaded, and adjust as needed
                            %switch isstruct(app.mainDataStruct(n).data.RIR_data_brief)
                            switch isfield(app.mainDataStruct(1).data,'RIR_data_brief')
                                case 1
                                    app.mainDataStruct(1).data.RIR_data = app.mainDataStruct(1).data.RIR_data_brief;
                            end
                    end                    
                    
                    % Make a frequency and time axis for current file
                    Fs = app.mainDataStruct(1).data.RIR_params.Fs; Ts = 1/Fs;
                    NN = size(app.mainDataStruct(1).data.RIR_data.IR,1);
                    df = Fs/NN;
                    T_End = NN*Ts;
                    app.mainDataStruct(1).data.fVec = [0:df:Fs-df]';
                    app.mainDataStruct(1).data.tVec = [0:Ts:T_End-Ts]';
                    
                    % Number of separate mic channels (will be this number of RIRs per data file)
                    % Check if old data format is being used and adjust as needed
                    switch isfield(app.mainDataStruct(1).data.RIR_params,'recChanList')
                        case 1
                            app.mainDataStruct(1).data.RIR_metadata.recChanList = app.mainDataStruct(1).data.RIR_params.recChanList;
                            app.mainDataStruct(1).data.RIR_metadata.channelNames = app.mainDataStruct(1).data.RIR_params.channelNames;
                    end
                    N_mics = length(app.mainDataStruct(1).data.RIR_metadata.recChanList);
                    for nMic = 1:N_mics
                        count2 = count2+1;
                        fileID      = num2cell(1);
                        fileLoc     = app.mainDataStruct(1).data.RIR_metadata.location;
                        fileSrc     = app.mainDataStruct(1).data.RIR_metadata.srcLoc;
                        fileRcvr    = app.mainDataStruct(1).data.RIR_metadata.recvrLoc;
                        fileSpkr    = app.mainDataStruct(1).data.RIR_metadata.speaker;
                        filethisMic = app.mainDataStruct(1).data.RIR_metadata.channelNames{nMic};
                        fileRun     = app.mainDataStruct(1).data.RIR_metadata.runlabel;
                        plotFlag    = num2cell(true(1,1));
                        dataIR      = app.mainDataStruct(1).data.RIR_data.IR(:,nMic);
                        dataFR      = app.mainDataStruct(1).data.RIR_data.FR(:,nMic);
                        dataFs      = app.mainDataStruct(1).data.RIR_params.Fs;
                        tab(count2,:) = [fileID,fileLoc,fileSrc,fileRcvr,fileSpkr,filethisMic,fileRun,plotFlag,num2cell(count2),dataFs];
                        tab2(count2,:) = {dataIR,dataFR,dataFs};
                        countAll{count2} = num2str(count2);
                    end
                
                case 1
                    N_Files = size(filename,2);
                    count   = 0;
                    count2  = 0;
                    %app.LoadData_CallingOption.Value = 0;
                    for n=1:N_Files
                        count = count+1;
                        app.mainDataStruct(n).data = load([pathname,filename{:,n}]);
                        
                        % Check if old data format is being loaded, and adjust as needed
                        %switch isstruct(app.mainDataStruct(n).data.RIR_data_brief)
                        switch isfield(app.mainDataStruct(n).data,'RIR_data_brief')
                            case 1
                                app.mainDataStruct(n).data.RIR_data = app.mainDataStruct(n).data.RIR_data_brief;
                        end
                        
                        % Make a frequency and time axis for current file
                        Fs = app.mainDataStruct(n).data.RIR_params.Fs; Ts = 1/Fs;
                        NN = size(app.mainDataStruct(n).data.RIR_data.IR,1);
                        df = Fs/NN;
                        T_End = NN*Ts;
                        app.mainDataStruct(n).data.fVec = [0:df:Fs-df]';
                        app.mainDataStruct(n).data.tVec = [0:Ts:T_End-Ts]';
                        
                        % Number of separate mic channels (will be this number of RIRs per data file)
                        % Check if old data format is being used and adjust as needed
                        switch isfield(app.mainDataStruct(n).data.RIR_params,'recChanList')                                                         
                            case 1                                
                                app.mainDataStruct(n).data.RIR_metadata.recChanList = app.mainDataStruct(n).data.RIR_params.recChanList;
                                app.mainDataStruct(n).data.RIR_metadata.channelNames = app.mainDataStruct(n).data.RIR_params.channelNames;
                        end
                        N_mics = length(app.mainDataStruct(n).data.RIR_metadata.recChanList);
                        for nMic = 1:N_mics
                            % Create a table of metadata for the current file and mic list
                            count2 = count2+1;
                            fileID      = num2cell(n);
                            fileLoc     = app.mainDataStruct(n).data.RIR_metadata.location;
                            fileSrc     = app.mainDataStruct(n).data.RIR_metadata.srcLoc;
                            fileRcvr    = app.mainDataStruct(n).data.RIR_metadata.recvrLoc;
                            fileSpkr    = app.mainDataStruct(n).data.RIR_metadata.speaker;
                            filethisMic = app.mainDataStruct(n).data.RIR_metadata.channelNames{nMic};
                            fileRun     = app.mainDataStruct(n).data.RIR_metadata.runlabel;
                            plotFlag    = num2cell(true(1,1));
                            dataIR      = app.mainDataStruct(n).data.RIR_data.IR(:,nMic);
                            dataFR      = app.mainDataStruct(n).data.RIR_data.FR(:,nMic);
                            dataFs      = app.mainDataStruct(n).data.RIR_params.Fs;
                            tab(count2,:) = [fileID,fileLoc,fileSrc,fileRcvr,fileSpkr,filethisMic,fileRun,plotFlag,num2cell(count2),dataFs];
                            tab2(count2,:) = {dataIR,dataFR,dataFs};
                            countAll{count2} = num2str(count2);
                        end
                    end
            end
            
            % Add a list of every IR (across all loaded files) into a drop down menu for listening back
            app.PlayRIRaudioDropDown.Items = countAll;
            app.PlayRIRaudioDropDown.ItemsData = 1:count2;
            
            % Write the constructed cell array of all loaded metadata into the data info table
            % Note that THIS ONLY CONTAINS METADATA - Actual IR/FR data stored in app.bigCell
            app.TabFiles.Data = tab;
            
            % And now write the massive data stack (cell array) into a globally accessible variable
            app.bigCell.data = tab2;
                        
            % Once done, if this function has been called by the measurement tab, immediately plot results
            pause(0.5);
            switch str2double(app.DataLoadingSwitch.Value)
                case 0
                    plottingFn(app);
            end
            
            % Data checking (dev only)
            %assignin('base', 'tab', app.TabFiles.Data);
            %assignin('base', 'mainDataStruct', app.mainDataStruct);
            %assignin('base', 'bigCell', app.bigCell);
        end

        % Value changed function: ZoomSwitch
        function ZoomSwitchValueChanged(app, event)
            value = app.ZoomSwitch.Value;
            switch value
                case 'On'
                    app.PanSwitch.Value = 'Off';
                    zoom(app.Axes_IR,'on');
                case 'Off'
                    zoom(app.Axes_IR,'off');
            end
        end

        % Value changed function: PanSwitch
        function PanSwitchValueChanged(app, event)
            value = app.PanSwitch.Value;
            switch value
                case 'On'
                    app.ZoomSwitch.Value = 'Off';
                    zoom(app.Axes_IR,'off');
                    pan(app.Axes_IR,'on');
                case 'Off'
                    pan(app.Axes_IR,'off');
            end
        end

        % Button pushed function: ResetviewButton
        function ResetviewButtonPushed(app, event)
            app.ZoomSwitch.Value  = 'Off';
            app.PanSwitch.Value   = 'Off';
            RIR_setAxes(app);
        end

        % Callback function: LinearLogfreqCheckBox, 
        % LinearLogtimeCheckBox, NormaliseRFRCheckBox, 
        % NormaliseRIRCheckBox, PlotdataButton
        function PlotdataButtonPushed(app, event)
            plottingFn(app);
        end

        % Value changed function: PanSwitch_2
        function PanSwitch_2ValueChanged(app, event)
            value = app.PanSwitch_2.Value;
            switch value
                case 'On'
                    app.ZoomSwitch_2.Value = 'Off';
                    zoom(app.Axes_FR,'off');
                    pan(app.Axes_FR,'on');
                case 'Off'
                    pan(app.Axes_FR,'off');
            end
        end

        % Value changed function: ZoomSwitch_2
        function ZoomSwitch_2ValueChanged(app, event)
            value = app.ZoomSwitch_2.Value;
            switch value
                case 'On'
                    app.PanSwitch_2.Value = 'Off';
                    zoom(app.Axes_FR,'on');
                case 'Off'
                    zoom(app.Axes_FR,'off');
            end
        end

        % Button pushed function: ResetviewButton_2
        function ResetviewButton_2Pushed(app, event)
            app.ZoomSwitch_2.Value  = 'Off';
            app.PanSwitch_2.Value   = 'Off';
            RFR_setAxes(app);
        end

        % Value changed function: dBhighEditField, dBlowEditField, 
        % fhighEditField, flowEditField
        function RFR_setAxes(app, event)
            switch app.LeaveAxes_RFRCheckBox.Value
                case 0
                    value1 = app.flowEditField.Value;
                    value2 = app.fhighEditField.Value;
                    app.Axes_FR.XLim = [value1,value2];
                    
                    value3 = app.dBlowEditField.Value;
                    value4 = app.dBhighEditField.Value;
                    app.Axes_FR.YLim = [value3,value4];
            end
        end

        % Value changed function: AmplhighEditField, 
        % AmpllowEditField, tendEditField, tstartEditField
        function RIR_setAxes(app, event)
            switch app.LeaveAxes_RIRCheckBox.Value
                case 0
                    value1 = app.tstartEditField.Value;
                    value2 = app.tendEditField.Value;
                    app.Axes_IR.XLim = [value1,value2];
                    
                    value3 = app.AmpllowEditField.Value;
                    value4 = app.AmplhighEditField.Value;
                    app.Axes_IR.YLim = [value3,value4];
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Pull out the file ID to be played, and play it
            % At the moment (2017/12/19) audioplayer doesn't work here
            fileInd    = app.PlayRIRaudioDropDown.Value;
            thisAudio  = app.bigCell.data{fileInd,1};
            thisFs     = app.bigCell.data{fileInd,3};
            T_play     = max(size(thisAudio))/thisFs;
            T_play_app =  app.PlaydurationEditField.Value;
            if T_play_app < T_play
                T_play = T_play_app;
                thisAudio = thisAudio(1:floor(T_play_app*thisFs),:);
            end
            
            % Flash a light on during playback so the user knows audio is ongoing
            app.AudioplayingLamp.Enable = 'on';
            soundsc(thisAudio,thisFs)
            tic
            while(toc<T_play)
                doneYet = 0;
            end
            doneYet = 1;
            switch doneYet
                case 1
                    app.AudioplayingLamp.Enable = 'off';
            end
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            N_Curves = max(size(app.TabFiles.Data(:,8)));
            app.TabFiles.Data(:,8) = num2cell(logical(ones(N_Curves,1)));
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            N_Curves = max(size(app.TabFiles.Data(:,8)));
            app.TabFiles.Data(:,8) = num2cell(logical(zeros(N_Curves,1)));
        end

        % Button pushed function: ExportWAVButton
        function ExportWAVButtonPushed(app, event)
            % Write a multi-channel wav file to disc with all selected curves
            
            % Start by getting list of selected curves (can be from different data files)
            [list,~]       = find(cell2mat(app.TabFiles.Data(:,8)));
            N_curves       = size(list,1);
            thisIR_CellRay = app.bigCell.data(list,1);
            %assignin('base', 'thisIR_CellRay', thisIR_CellRay);
            %assignin('base', 'Fs_Mat', cell2mat(app.TabFiles.Data(:,10)));
            
            % Grab the associated sample rates for each curve, and check they are all the same
            % before proceeding to construct output matrix (all columns same length!)
            theseFs      = cell2mat(app.bigCell.data(list,3));
            checkFs      = all(theseFs == theseFs(1));
            switch checkFs
                case 0
                    % If samples rates not equal, stop here and update status text
                    app.StatusTextArea.Value = 'No .WAV export: Invalid sample rates';
                case 1
                    % Find out how long each curve is, and the longest one
                    theseCurveLengths = cellfun('length',thisIR_CellRay);
                    maxCurveLength    = max(theseCurveLengths);
                    
                    % Check for output normalisation requested
                    optionNorm = app.NormEachCurveCheckBox.Value;
                    
                    % 0.1dBFS norm factor
                    normFactor = 10^(0.1/20);
                    
                    % Find out if user wishes to control duration of output RIRs
                    optionTruncate  = app.TruncateIRCheckBox.Value;
                    switch optionTruncate
                        case 1
                            % Output a multi channel wav file that is as long as user requests
                            userRIR_T_start = app.RIRTstartEditField.Value;
                            userRIR_T_end   = app.RIRdurationEditField.Value;
                            userRIR_N_start = floor(userRIR_T_start*theseFs(1))+1;
                            userRIR_N_end   = floor(userRIR_T_end*theseFs(1));
                            
                        case 0
                            % Output a multi channel wav file that is as long as longest input RIR
                            userRIR_N_start = 1;
                            userRIR_N_end   = maxCurveLength;
                    end
                    
                    % Loop through all chosen curves, cutting or zero padding as needed
                    for nCurve = 1:N_curves
                        thisCurve = thisIR_CellRay{nCurve};
                        thisN     = length(thisCurve);
                        if userRIR_N_start>length(thisCurve)
                            error('Error: Start time for RIRs is longer than duration of RIRs! Lower the start time and try again')
                        elseif thisN<(userRIR_N_start+userRIR_N_end)
                            % User is creating a RIR curve that is longer than data: Zero pad!
                            zeroPad = zeros(userRIR_N_start+userRIR_N_end-thisN-1,1);
                            thisCurve = [thisCurve(userRIR_N_start:end);zeroPad];
                        elseif thisN>(userRIR_N_start+userRIR_N_end)
                            % User is cutting file to shorter length: Easy!
                            thisCurve = thisCurve(userRIR_N_start:userRIR_N_end);
                        end
                        
                        % Normalisation
                        switch optionNorm
                            case 1
                                % Each curve normalised to 0.1dBFS
                                thisCurve = (1/normFactor)*thisCurve/max(abs(thisCurve));
                        end
                        
                        % Add a new column to output wav matrix
                        thisOutputWav(:,nCurve) = thisCurve;
                    end
                    
                    
                    % Now the data is ready, prompt the user for output filename
                    [file,path] = uiputfile('*.wav','Save .WAV file as (***DO NOT ADD .WAV EXTENTION***)');
                    fullpath = [path,file];
                    
                    switch optionNorm
                        case 0
                            % Normalise each column by loudest column
                            thisOutputWav = (1/normFactor)*thisOutputWav/max(max(abs(thisOutputWav)));
                            
                            % Now write to disk
                            audiowrite(fullpath,thisOutputWav,theseFs(1),'BitsPerSample',24);
                        case 1
                            % Data already normalised, so write to disk
                            audiowrite(fullpath,thisOutputWav,theseFs(1),'BitsPerSample',24);
                    end
                    
                    % Update status text to confirm export
                    app.StatusTextArea.Value = ['.WAV file containing ' num2str(N_curves) ' channels exported!'];
            end
        end

        % Value changed function: NormEachCurveCheckBox
        function NormEachCurveCheckBoxValueChanged(app, event)
            value = app.NormEachCurveCheckBox.Value;
            switch value
                case 1
                    app.NormPeakCurveCheckBox.Value = 0;
                case 0
                    app.NormPeakCurveCheckBox.Value = 1;
            end
            
        end

        % Value changed function: NormPeakCurveCheckBox
        function NormPeakCurveCheckBoxValueChanged(app, event)
            value = app.NormPeakCurveCheckBox.Value;
            switch value
                case 1
                    app.NormEachCurveCheckBox.Value = 0;
                case 0
                    app.NormEachCurveCheckBox.Value = 1;
            end
        end

        % Cell edit callback: TabFiles
        function TabFilesCellEdit(app, event)
            %app.plotList.list = cell2mat(app.TabFiles.Data(:,8))
        end

        % Cell selection callback: TabFiles
        function TabFilesCellSelection(app, event)
            %app.plotList.list = cell2mat(app.TabFiles.Data(:,8))
        end

        % Value changed function: LinearLogamplCheckBox
        function LinearLogamplCheckBoxValueChanged(app, event)
            value = app.LinearLogamplCheckBox.Value;
            switch value
                case 0
                    app.AmplhighEditField.Value = 1;
                    app.AmpllowEditField.Value = -1;
                case 1
                    app.AmplhighEditField.Value = 0;
                    app.AmpllowEditField.Value = -80;
            end
            plottingFn(app);
            
        end

        % Value changed function: LinearLogmagCheckBox
        function LinearLogmagCheckBoxValueChanged(app, event)
            value = app.LinearLogmagCheckBox.Value;
            switch value
                case 0
                    app.dBhighEditField.Value = 1;
                    app.dBlowEditField.Value = 0;
                case 1
                    app.dBhighEditField.Value = 0;
                    app.dBlowEditField.Value = -80;
            end
            plottingFn(app);
        end

        % Button pushed function: FindPlayrecDevButton
        function FindPlayrecDevButtonPushed(app, event)
            % This callback querries playrec to obtain available devices,
            % which are then displayed to user in a drop down menu
            
            % First, check whether any data is in memory, and wipe if yes           
            clear app.mainDataStruct
            
            % First check that playrec is visible to Matlab
            switch exist('playrec')
                case 3
                    app.Playrec_Status_Text.Value = 'Playrec live and well!';
                otherwise
                    error('Error: Playrec MEX file is not visible to Matlab. Unable to continue.')
                    app.Playrec_Status_Text.Value = 'Playrec not found!';
            end
            
            % Now get list of devices
            app.deviceStruct.all           = playrec('getDevices');
            app.deviceStruct.deviceNames   = {app.deviceStruct.all.name}';
            app.OutputdeviceDropDown.Items = app.deviceStruct.deviceNames;
            app.InputdeviceDropDown.Items  = app.deviceStruct.deviceNames;
        end

        % Value changed function: OutputdeviceDropDown
        function OutputdeviceDropDownValueChanged(app, event)
            % User changes selected output device, so update output devices table with specs
            % Value here is a string
            thisDevice = app.OutputdeviceDropDown.Value;
            [q,~] = find(strcmp(app.deviceStruct.deviceNames, thisDevice));
            app.deviceStruct.deviceID_Play = app.deviceStruct.all(q).deviceID;
            chanList = [1:app.deviceStruct.all(q).outputChans]';
            switch isempty(chanList)
                case 0
                    chanList_Cell                       = sprintfc('%d',chanList);
                    app.OutputchannelDropDown.Items     = chanList_Cell;
                    app.OutputchannelDropDown.ItemsData = chanList;
                case 1
                    app.OutputchannelDropDown.Items     = {};
                    app.OutputchannelDropDown.ItemsData = [];
            end
            
        end

        % Value changed function: InputdeviceDropDown
        function InputdeviceDropDownValueChanged(app, event)
            thisDevice = app.InputdeviceDropDown.Value;
            [q,~] = find(strcmp(app.deviceStruct.deviceNames, thisDevice));
            app.deviceStruct.deviceID_Rec = app.deviceStruct.all(q).deviceID;
            chanList = [1:app.deviceStruct.all(q).inputChans]';
            switch isempty(chanList)
                case 0
                    chanList_Cell       = num2cell(chanList);
                    for nName=1:length(chanList)
                        chanNames_Cell{nName,1} = ['Mic ' num2str(nName)];
                        chanNames_Cell2{nName,1} = num2str(nName);
                    end
                    chanSel             = false(app.deviceStruct.all(q).inputChans,1);
                    chanSel(1)          = 1;
                    chanSel             = num2cell(chanSel);
                    Playrec_Ins_Table   = [chanList_Cell,chanNames_Cell,chanSel];
                    app.MicTable.Data   = Playrec_Ins_Table;
                    app.ClicksyncchanDropDown.ItemsData = chanList_Cell;
                    app.ClicksyncchanDropDown.Items     = chanNames_Cell2;
                    
                case 1
                    chanList_Cell       = {};
                    app.MicTable.Data   = [chanList_Cell,{}];
                    app.ClicksyncchanDropDown.Items = chanList_Cell;
            end
        end

        % Button pushed function: MeasureRIRButton
        function MeasureRIRButtonPushed(app, event)
            % This callback carries out a new RIR measurement, using code organised into the
            % subfolder "Code_MikIRAM"
            
            % First of all, make sure 'export' IR duration text box matches number in sweep spec
            app.T_RIR_Duration_Export.Value = app.T_RIR_Duration_Main.Value;
            app.tendEditField_RIR.Value     = app.T_RIR_Duration_Main.Value;
            
            
            % First gather all metadata into a structure
            RIR_metadata.playChanList   = app.OutputchannelDropDown.Value;
            RIR_metadata.N_chansPlay    = length(app.OutputchannelDropDown.ItemsData);
            RIR_metadata.runlabel       = app.RunlabelEditField.Value;
            RIR_metadata.srcLoc         = app.SourcelocEditField.Value;
            RIR_metadata.recvrLoc       = app.RecvrlocEditField.Value;
            RIR_metadata.location       = [app.MaindatafolderEditField.Value '/' app.RoomnameEditField.Value];
            %RIR_metadata.location       = app.RoomnameEditField.Value;
            RIR_metadata.speaker        = app.LoudspeakerEditField.Value;
            RIR_metadata.src_recvr_dist = app.SourceReceiverdistanceEditField.Value;
            RIR_metadata.date           = app.DateEditField.Value;
            RIR_metadata.extraText      = app.ExtranotesEditField.Value;
            
            % Add on input channel info/metadata
            RIR_metadata.interfaceName_Play = app.OutputdeviceDropDown.Value;
            RIR_metadata.interfaceName_Rec  = app.InputdeviceDropDown.Value;
            RIR_metadata.interfaceID_Play = app.deviceStruct.deviceID_Play;
            RIR_metadata.interfaceID_Rec  = app.deviceStruct.deviceID_Rec;
            micSelected                 = cell2mat(app.MicTable.Data(:,3));
            micList                     = (cell2mat(app.MicTable.Data(:,1)).*micSelected)';
            micList(micList==0)         = [];
            RIR_metadata.recChanList    = micList;
            RIR_metadata.N_chansRec     = length(app.MicTable.Data(:,2));
            count = 0;
            for nMic = 1:length(micSelected)
                switch micSelected(nMic)
                    case 1
                        count = count+1;
                        RIR_metadata.channelNames{count} = app.MicTable.Data{nMic,2};
                end
            end
            
            % Then gather all sweep info into a structure
            RIR_params.sigVolumedBFS    = app.Sweep_Volume.Value;
            RIR_params.volumeClickdBFS  = app.Click_Volume.Value;
            RIR_params.Fs               = app.SamplerateDropDown.Value;
            RIR_params.f_Start          = app.Sweep_fLow.Value;
            RIR_params.f_End            = app.Sweep_fHigh.Value;
            RIR_params.T_SigLength      = app.Sweep_Tdur.Value;
            RIR_params.IR_duration      = app.T_RIR_Duration_Main.Value;
            RIR_params.T_Wait           = app.PreMeasure_Tpause.Value;
            RIR_params.T_Fade           = app.Sweep_Tfade.Value;
            RIR_params.optionClick      = app.UseclicksyncCheckBox.Value;
            RIR_params.clickDetectChan  = app.ClicksyncchanDropDown.Value;
            RIR_params.T_ClickPause     = app.Sweep_Tclickgap.Value;
            RIR_params.N_Averages       = app.NSweepAveragesEditField.Value;
            RIR_params.T_Pad            = 0; %app.Sweep_Trep_gap.Value;
            RIR_params.IR_preCutSamples = floor(app.T_RIR_PreCut_DurationBox.Value*RIR_params.Fs);
            RIR_params.signalType       = 'logsweep';
            RIR_params.optionKirkeby    = app.UseKirkebyCheckBox.Value;
            RIR_params.df_Kirkeby_low   = app.Kirkeby_fTransition.Value;
            RIR_params.beta_Pass_dB     = app.Kirkeby_Passband_dB.Value;
            RIR_params.beta_Limit_dB    = app.Kirkeby_Stopband_dB.Value;
            
            % Control page (i.e. buffer) size from drop down
            q = app.BufferLengthFactorDropDown.Value;
            if strcmp(q,'0 (default)')
                RIR_params.pageSize         = 0;
            else
                RIR_params.pageSize         = str2double(q)*128;
            end
            
            
            %-------------------------------------------------------------------------%
            % (02) Basic error checking
            %-------------------------------------------------------------------------%
            [RIR_metadata] = MikRIR_02_ErrorCheck_Fn__V5(RIR_metadata,RIR_params,1);
            
            
            %-------------------------------------------------------------------------%
            % (03) Make excitaton signal.
            % Call the function that makes a test signal (and if relevant, it's inverse
            % filter (possibly with regularisation applied, if specified above)
            %-------------------------------------------------------------------------%
            [RIR_metadata,RIR_params,RIR_data] = MikRIR_03_MakeSig_Fn__V5(RIR_metadata,RIR_params);
            
            
            %-------------------------------------------------------------------------%
            % (04) Data output/input (via playrec).
            % Call the main data acquisition function, which uses playrec, getting
            % back the raw audio data
            %-------------------------------------------------------------------------%
            app.DataacquiredLamp.Enable     = 'off';
            app.MeasurementliveLamp.Enable  = 'on';
            [RIR_metadata,RIR_params,RIR_data] = MikRIR_04_Acquire_Fn__V5(RIR_metadata,RIR_params,RIR_data);
            app.tendEditField_2.Value = 2*app.Sweep_Tdur.Value;
            
            
            %-------------------------------------------------------------------------%
            % (05) Data analysis
            % Call the analysis function, which performs deconvolution (if required)
            % and basic signal analysis
            %-------------------------------------------------------------------------%
            [RIR_metadata,RIR_params,RIR_data] = MikRIR_05_Analysis_Fn__V5(RIR_metadata,RIR_params,RIR_data);
            
            
            %-------------------------------------------------------------------------%
            % (06) Plot data if required in diagnostics window
            %-------------------------------------------------------------------------%
            switch app.PlotDiagnosticsCheckBox.Value
                case 1
                    %[RIR_metadata] = MikRIR_06_Plotting_Fn__V5(RIR_metadata,RIR_params,RIR_data);
                    [~] = MikRIR_06_Plotting_Fn__V5(RIR_metadata,RIR_params,RIR_data);
            end
            
            
            %-------------------------------------------------------------------------%
            % (07) Pass newly measured data into 'app' so it's available elsewhere,
            % and call the MikRIR Plotter tab to plot it there
            %-------------------------------------------------------------------------%
            % Now save the acquired data/metadata so it's available to other parts of the app
            app.mainDataStruct(1).data.RIR_metadata = RIR_metadata;
            app.mainDataStruct(1).data.RIR_params   = RIR_params;
            app.mainDataStruct(1).data.RIR_data     = RIR_data;
            
            
            %-------------------------------------------------------------------------%
            % (08) Plot raw measured mic signals in the mic data windows (check for clipping!!)
            %-------------------------------------------------------------------------%
            plottingFn_Meas(app)
            
            
            %-------------------------------------------------------------------------%
            % (09) Call the MikRIR Plotter tab to plot computed IRs/FRs there
            %-------------------------------------------------------------------------%
            app.DataLoadingSwitch.Value = '0';
            app.LoadMikRIRdataMATButton.Enable = 'Off';
            
            %LoadMikRIRdataMATButtonPushed(app);
            
            % Turn off/on the appropriate lights to tell user that data is ready
            app.MeasurementliveLamp.Enable  = 'off';
            app.DataacquiredLamp.Enable     = 'on';
            
            % Write data into workspace for checking
            %assignin('base', 'RIR_params', RIR_params);
            %assignin('base', 'RIR_metadata', RIR_metadata);
            %assignin('base', 'RIR_data', RIR_data);
            
        end

        % Button pushed function: ReplotRIRsButton
        function ReplotRIRsButtonPushed(app, event)
            % When user clicks this, cut/zero pad all RIRs as required, replot, and update data structures
            % This allows the user to trim their RIRs after the fact, 'live', before data export
            
            % This callback controls the duration of the final exported/saved RIRs
            % It is called if the user changes the pushes the 'replot RIRs' button in the export panel
            % This callback updates the relevant metadata for the current measurement,
            % and then replots the RIRs
            
            % Workflow:
            %   (1) Updated metadata
            %   (2) Recut final RIRs and store in data struct
            %   (3) Call plotting function (same function used following 'Measure' button press)
            
            % Now check whether a measurement has been made yet, or not. Proceed only if yes.
            switch app.DataacquiredLamp.Enable
                case 'off'
                    
                case 'on'
                    % If this lamp is green, a measurement must have been made, so proceed
                    % Note that no recomputation of the deconvolution is needed, as we have the 'raw' RIR signal to work from
                    
                    % (1) Get the requested RIR duration, and precut time
                    T_RIR    = app.T_RIR_Duration_Export.Value;
                    T_preCut = app.T_RIR_PreCut_DurationBox.Value;                    
                    Fs       = app.mainDataStruct(1).data.RIR_params.Fs;
                    
                    app.mainDataStruct(1).data.RIR_params.IR_preCutSamples = floor(T_preCut*Fs);
                    app.mainDataStruct(1).data.RIR_params.IR_duration      = T_RIR;
                    
                    % (2) Call the function that trims RIRs as requested
                    [app.mainDataStruct(1).data.RIR_metadata,app.mainDataStruct(1).data.RIR_params,app.mainDataStruct(1).data.RIR_data] ...
                        = MikRIR_00_RIR_Cutter_Fn__V5(app.mainDataStruct(1).data.RIR_metadata,app.mainDataStruct(1).data.RIR_params,app.mainDataStruct(1).data.RIR_data);
                    
                    % (3) And now the plotting function
                    plottingFn_Meas(app)
            end
        end

        % Value changed function: OutputchannelDropDown
        function OutputchannelDropDownValueChanged(app, event)
            % We only allow mono output, so if one channel is selected, all others must be muted
            value = app.OutputchannelDropDown.Value;
        end

        % Value changed function: BufferLengthFactorDropDown
        function BufferLengthFactorDropDownValueChanged(app, event)
            value = app.BufferLengthFactorDropDown.Value;
            
            % Control page (i.e. buffer) size from drop down
            q = app.BufferLengthFactorDropDown.Value;
            if strcmp(q,'0 (default)')
                app.BufferLengthDisplay.Value = 'Auto controlled (default)';
            else
                app.BufferLengthDisplay.Value = num2str(str2double(q)*128);
            end
        end

        % Button pushed function: ExportdatafileButton
        function ExportdatafileButtonPushed(app, event)
            % If pressed, save the acquired data, as long as it's in the workspace from a new measurement!
            optionCustom = app.CustomExportPathCheckBox.Value;
            
            % Only save data to a file if it has just been measured freshly
            str2double(app.DataLoadingSwitch.Value)
            switch str2double(app.DataLoadingSwitch.Value)
                case 1
                    disp('No data saved! You have just loaded old data - this button is for saving new data only!')
                case 0
                    switch app.DataacquiredLamp.Enable
                        case 'on'
                            MikRIR_07_SaveData_Fn__V5(app.mainDataStruct(1).data.RIR_metadata,app.mainDataStruct(1).data.RIR_params,app.mainDataStruct(1).data.RIR_data,optionCustom);
                        case 'off'
                            disp('No data saved! No data yet acquired!')
                    end
            end


        end

        % Value changed function: DataLoadingSwitch
        function DataLoadingSwitchValueChanged(app, event)
            value = app.DataLoadingSwitch.Value;
            % This turns the main 'Load data' button on or off
            switch value
                case '0'
                    app.LoadMikRIRdataMATButton.Enable = 'Off';
                case '1'
                    app.LoadMikRIRdataMATButton.Enable = 'On';
            end
            
        end

        % Value changed function: TruncateIRCheckBox
        function TruncateIRCheckBoxValueChanged(app, event)
            value = app.TruncateIRCheckBox.Value;
            % If turned off, prevent user from editing the start/end timings for export of wav files
            switch value
                case 0
                    app.RIRTstartEditField.Enable = 'Off';
                    app.RIRdurationEditField.Enable = 'Off';
                case 1
                    app.RIRTstartEditField.Enable = 'On';
                    app.RIRdurationEditField.Enable = 'On';
            end
        end

        % Value changed function: PanSwitchMics
        function PanSwitchMicsValueChanged(app, event)
            value = app.PanSwitchMics.Value;
            switch value
                case 'On'
                    app.ZoomSwitchMics.Value = 'Off';
                    zoom(app.MeasAxes_Sweeps,'off');
                    pan(app.MeasAxes_Sweeps,'on');
                case 'Off'
                    pan(app.MeasAxes_Sweeps,'off');
            end
        end

        % Value changed function: ZoomSwitchMics
        function ZoomSwitchMicsValueChanged(app, event)
            value = app.ZoomSwitchMics.Value;
            switch value
                case 'On'
                    app.PanSwitchMics.Value = 'Off';
                    zoom(app.MeasAxes_Sweeps,'on');
                    pan(app.MeasAxes_Sweeps,'off');
                case 'Off'
                    zoom(app.MeasAxes_Sweeps,'off');
            end
        end

        % Button pushed function: ResetviewButtonMics
        function ResetviewButtonMicsPushed(app, event)
            app.ZoomSwitch.Value  = 'Off';
            app.PanSwitch.Value   = 'Off';
            Mics_setAxes(app);
        end

        % Value changed function: AmplEditField, AmplEditField_2, 
        % tendEditField_2, tstartEditField_2
        function Mics_setAxes(app, event)
            value1 = app.tstartEditField_2.Value;
            value2 = app.tendEditField_2.Value;
            app.MeasAxes_Sweeps.XLim = [value1,value2];
            
            value3 = app.AmplEditField_2.Value;
            value4 = app.AmplEditField.Value;
            app.MeasAxes_Sweeps.YLim = [value3,value4];
        end

        % Value changed function: T_RIR_Duration_Main
        function T_RIR_Duration_MainValueChanged(app, event)
            value = app.T_RIR_Duration_Main.Value;
            app.T_RIR_Duration_Export.Value = value;
            app.tendEditField_RIR.Value = value;
            
            Sweep_TdurValueChanged(app);
        end

        % Value changed function: PanSwitchRIRsMeas
        function PanSwitchRIRsMeasValueChanged(app, event)
            value = app.PanSwitchRIRsMeas.Value;
            switch value
                case 'On'
                    app.ZoomSwitchRIRsMeas.Value = 'Off';
                    zoom(app.MeasAxes_RIR,'off');
                    pan(app.MeasAxes_RIR,'on');
                case 'Off'
                    pan(app.MeasAxes_RIR,'off');
            end
        end

        % Value changed function: ZoomSwitchRIRsMeas
        function ZoomSwitchRIRsMeasValueChanged(app, event)
            value = app.ZoomSwitchRIRsMeas.Value;
            switch value
                case 'On'
                    app.PanSwitchRIRsMeas.Value = 'Off';
                    zoom(app.MeasAxes_RIR,'on');
                    pan(app.MeasAxes_RIR,'off');
                case 'Off'
                    zoom(app.MeasAxes_RIR,'off');
            end
        end

        % Value changed function: T_RIR_Duration_Export
        function T_RIR_Duration_ExportValueChanged(app, event)
            value = app.T_RIR_Duration_Export.Value;
            app.tendEditField_RIR.Value = value;
            
            
        end

        % Value changed function: PanSwitchRIRsMeas_RAW
        function PanSwitchRIRsMeas_RAWValueChanged(app, event)
            value = app.PanSwitchRIRsMeas_RAW.Value;
            switch value
                case 'On'
                    app.ZoomSwitchRIRsMeas_RAW.Value = 'Off';
                    zoom(app.MeasAxes_RIR_RAW,'off');
                    pan(app.MeasAxes_RIR_RAW,'on');
                case 'Off'
                    pan(app.MeasAxes_RIR_RAW,'off');
            end
        end

        % Value changed function: ZoomSwitchRIRsMeas_RAW
        function ZoomSwitchRIRsMeas_RAWValueChanged(app, event)
            value = app.ZoomSwitchRIRsMeas_RAW.Value;
            switch value
                case 'On'
                    app.PanSwitchRIRsMeas_RAW.Value = 'Off';
                    zoom(app.MeasAxes_RIR_RAW,'on');
                    pan(app.MeasAxes_RIR_RAW,'off');
                case 'Off'
                    zoom(app.MeasAxes_RIR_RAW,'off');
            end
        end

        % Button pushed function: ResetviewButtonRIRMeas_RAW
        function ResetviewButtonRIRMeas_RAWPushed(app, event)
            app.PanSwitchRIRsMeas_RAW.Value  = 'Off';
            app.ZoomSwitchRIRsMeas_RAW.Value   = 'Off';
            Mics_setAxes_RIR_RAW(app);
        end

        % Value changed function: AmplEditFieldHigh_RIR_RAW, 
        % AmplEditFieldLow_RIR_RAW, tendEditField_RIR_RAW, 
        % tstartEditField_RIR_RAW
        function Mics_setAxes_RIR_RAW(app, event)
            value1 = app.tstartEditField_RIR_RAW.Value;
            value2 = app.tendEditField_RIR_RAW.Value;
            app.MeasAxes_RIR_RAW.XLim = [value1,value2];
            
            value3 = app.AmplEditFieldLow_RIR_RAW.Value;
            value4 = app.AmplEditFieldHigh_RIR_RAW.Value;
            app.MeasAxes_RIR_RAW.YLim = [value3,value4];
        end

        % Value changed function: Sweep_Tdur
        function Sweep_TdurValueChanged(app, event)
            value = app.Sweep_Tdur.Value;
            app.tendEditField_RIR_RAW.Value = value*2 + app.T_RIR_Duration_Main.Value;
        end

        % Value changed function: AmplEditFieldHigh_RIR, 
        % AmplEditFieldLow_RIR, tendEditField_RIR, tstartEditField_RIR
        function Mics_setAxes_RIR_Final(app, event)
            value1 = app.tstartEditField_RIR.Value;
            value2 = app.tendEditField_RIR.Value;
            app.MeasAxes_RIR.XLim = [value1,value2];
            
            value3 = app.AmplEditFieldLow_RIR.Value;
            value4 = app.AmplEditFieldHigh_RIR.Value;
            app.MeasAxes_RIR.YLim = [value3,value4];
            
        end

        % Button pushed function: ResetviewButtonRIRMeas
        function ResetviewButtonRIRMeasPushed(app, event)
            app.PanSwitchRIRsMeas.Value  = 'Off';
            app.ZoomSwitchRIRsMeas.Value   = 'Off';
            Mics_setAxes_RIR_Final(app);
        end

        % Button pushed function: PassdatatoMikRIRPlotterButton
        function PassdatatoMikRIRPlotterButtonPushed(app, event)
            % Pass data through to main plotting tab
            % No further data processing is available there, but FR curves are
            app.tendEditField.Value = app.T_RIR_Duration_Export.Value;
            
            LoadMikRIRdataMATButtonPushed(app);                                   
        end

        % Value changed function: LinearLogamplCheckBox_3
        function LinearLogamplCheckBox_3ValueChanged(app, event)
            value = app.LinearLogamplCheckBox_3.Value;            
            switch value
                case 0
                    app.AmplEditFieldHigh_RIR.Value = 1;
                    app.AmplEditFieldLow_RIR.Value = -1;
                case 1
                    app.AmplEditFieldHigh_RIR.Value = 0;
                    app.AmplEditFieldLow_RIR.Value = -80;
            end
            plottingFn_Meas(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MikRIR_Plotting_Utility = uifigure;
            app.MikRIR_Plotting_Utility.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.MikRIR_Plotting_Utility);
            app.MikIRAMMeasureTab = uitab(app.TabGroup);
            app.MikIRAMMeasureTab.Title = 'MikIRAM Measure';
            app.AudioIntPanel = uipanel(app.MikIRAMMeasureTab);
            app.AudioIntPanel.Title = 'Audio interface setup';
            app.MicTable = uitable(app.AudioIntPanel);
            app.MicTable.ColumnName = {'Chan #'; 'Mic ID (NAME YOUR MICS!)'; 'Enable?'};
            app.MicTable.RowName = {};
            app.MicTable.ColumnEditable = [false true true];
            app.OutputdeviceDropDownLabel = uilabel(app.AudioIntPanel);
            app.OutputdeviceDropDownLabel.Text = 'Output device';
            app.OutputdeviceDropDown = uidropdown(app.AudioIntPanel);
            app.OutputdeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputdeviceDropDownValueChanged, true);
            app.InputdeviceDropDownLabel = uilabel(app.AudioIntPanel);
            app.InputdeviceDropDownLabel.Text = 'Input device';
            app.InputdeviceDropDown = uidropdown(app.AudioIntPanel);
            app.InputdeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @InputdeviceDropDownValueChanged, true);
            app.FindPlayrecDevButton = uibutton(app.AudioIntPanel, 'push');
            app.FindPlayrecDevButton.ButtonPushedFcn = createCallbackFcn(app, @FindPlayrecDevButtonPushed, true);
            app.FindPlayrecDevButton.Text = 'Find playrec audio devices';
            app.Playrec_Status_Text = uitextarea(app.AudioIntPanel);
            app.Playrec_Status_Text.Value = {'Playrec status'};
            app.OutputchannelDropDownLabel = uilabel(app.AudioIntPanel);
            app.OutputchannelDropDownLabel.Text = 'Output channel';
            app.OutputchannelDropDown = uidropdown(app.AudioIntPanel);
            app.OutputchannelDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputchannelDropDownValueChanged, true);
            app.BufferlengthDropDownLabel = uilabel(app.AudioIntPanel);
            app.BufferlengthDropDownLabel.Text = 'Buffer length';
            app.BufferLengthFactorDropDown = uidropdown(app.AudioIntPanel);
            app.BufferLengthFactorDropDown.Items = {'0 (default)', '1', '2', '4', '8', '16', '32', '64', '128', '256', '512'};
            app.BufferLengthFactorDropDown.ValueChangedFcn = createCallbackFcn(app, @BufferLengthFactorDropDownValueChanged, true);
            app.BufferLengthFactorDropDown.Value = '0 (default)';
            app.BufferLengthDisplay = uitextarea(app.AudioIntPanel);
            app.BufferLengthDisplay.Editable = 'off';
            app.BufferLengthDisplay.Value = {'Auto controlled (default)'};
            app.MetadataPanel = uipanel(app.MikIRAMMeasureTab);
            app.MetadataPanel.Title = 'Metadata';
            app.RunEditFieldLabel = uilabel(app.MetadataPanel);
            app.RunEditFieldLabel.Text = 'Run #';
            app.RunlabelEditField = uieditfield(app.MetadataPanel, 'text');
            app.RunlabelEditField.Value = 'Take_01';
            app.SourcelocEditFieldLabel = uilabel(app.MetadataPanel);
            app.SourcelocEditFieldLabel.Text = 'Source loc';
            app.SourcelocEditField = uieditfield(app.MetadataPanel, 'text');
            app.SourcelocEditField.Value = 'Stage';
            app.RecvrlocEditFieldLabel = uilabel(app.MetadataPanel);
            app.RecvrlocEditFieldLabel.Text = 'Recvr loc';
            app.RecvrlocEditField = uieditfield(app.MetadataPanel, 'text');
            app.RecvrlocEditField.Value = 'Audience';
            app.SpeakerIDEditFieldLabel = uilabel(app.MetadataPanel);
            app.SpeakerIDEditFieldLabel.Text = 'Speaker ID';
            app.LoudspeakerEditField = uieditfield(app.MetadataPanel, 'text');
            app.LoudspeakerEditField.Value = 'Genelec 8030A';
            app.SourceReceiverdistanceEditFieldLabel = uilabel(app.MetadataPanel);
            app.SourceReceiverdistanceEditFieldLabel.Text = 'Source-Receiver distance';
            app.SourceReceiverdistanceEditField = uieditfield(app.MetadataPanel, 'numeric');
            app.SourceReceiverdistanceEditField.ValueDisplayFormat = '%.2f';
            app.mLabel = uilabel(app.MetadataPanel);
            app.mLabel.Text = 'm';
            app.DateEditFieldLabel = uilabel(app.MetadataPanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.MetadataPanel, 'text');
            app.ExtranotesEditFieldLabel = uilabel(app.MetadataPanel);
            app.ExtranotesEditFieldLabel.Text = 'Extra notes';
            app.ExtranotesEditField = uieditfield(app.MetadataPanel, 'text');
            app.ExtranotesEditField.Value = 'What did you have for breakfast?';
            app.RoomnameEditFieldLabel = uilabel(app.MetadataPanel);
            app.RoomnameEditFieldLabel.Text = 'Room name';
            app.RoomnameEditField = uieditfield(app.MetadataPanel, 'text');
            app.RoomnameEditField.Value = 'Test_Room';
            app.SweepSetupPanel = uipanel(app.MikIRAMMeasureTab);
            app.SweepSetupPanel.Title = 'Sweep setup';
            app.SamplerateDropDownLabel = uilabel(app.SweepSetupPanel);
            app.SamplerateDropDownLabel.Text = 'Sample rate';
            app.SamplerateDropDown = uidropdown(app.SweepSetupPanel);
            app.SamplerateDropDown.Items = {'44100', '48000'};
            app.SamplerateDropDown.Value = '48000';
            app.HzLabel_3 = uilabel(app.SweepSetupPanel);
            app.HzLabel_3.Text = 'Hz';
            app.flowEditField_2Label = uilabel(app.SweepSetupPanel);
            app.flowEditField_2Label.Text = 'f (low)';
            app.Sweep_fLow = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Sweep_fLow.Limits = [0 20000];
            app.Sweep_fLow.ValueDisplayFormat = '%.0f';
            app.Sweep_fLow.Value = 20;
            app.HzLabel_4 = uilabel(app.SweepSetupPanel);
            app.HzLabel_4.Text = 'Hz';
            app.HzLabel_5 = uilabel(app.SweepSetupPanel);
            app.HzLabel_5.Text = 'Hz';
            app.fhighEditField_2Label = uilabel(app.SweepSetupPanel);
            app.fhighEditField_2Label.Text = 'f (high)';
            app.Sweep_fHigh = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Sweep_fHigh.Limits = [1 23999];
            app.Sweep_fHigh.ValueDisplayFormat = '%.0f';
            app.Sweep_fHigh.Value = 20000;
            app.sLabel_6 = uilabel(app.SweepSetupPanel);
            app.sLabel_6.Text = 's';
            app.TSweepdurLabel = uilabel(app.SweepSetupPanel);
            app.TSweepdurLabel.Text = 'T Sweep dur';
            app.Sweep_Tdur = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Sweep_Tdur.Limits = [0.2 120];
            app.Sweep_Tdur.ValueDisplayFormat = '%.2f';
            app.Sweep_Tdur.ValueChangedFcn = createCallbackFcn(app, @Sweep_TdurValueChanged, true);
            app.Sweep_Tdur.Value = 10;
            app.sLabel_8 = uilabel(app.SweepSetupPanel);
            app.sLabel_8.Text = 's';
            app.TPausedurLabel = uilabel(app.SweepSetupPanel);
            app.TPausedurLabel.Text = 'T Pause dur';
            app.PreMeasure_Tpause = uieditfield(app.SweepSetupPanel, 'numeric');
            app.PreMeasure_Tpause.Limits = [0.1 60];
            app.PreMeasure_Tpause.ValueDisplayFormat = '%.2f';
            app.PreMeasure_Tpause.Editable = 'off';
            app.PreMeasure_Tpause.Value = 1;
            app.sLabel_9 = uilabel(app.SweepSetupPanel);
            app.sLabel_9.Text = 's';
            app.TFadedurLabel = uilabel(app.SweepSetupPanel);
            app.TFadedurLabel.Text = 'T Fade dur';
            app.Sweep_Tfade = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Sweep_Tfade.Limits = [0 1];
            app.Sweep_Tfade.ValueDisplayFormat = '%.2f';
            app.Sweep_Tfade.Value = 0.1;
            app.UseclicksyncCheckBox = uicheckbox(app.SweepSetupPanel);
            app.UseclicksyncCheckBox.Text = 'Use click sync?';
            app.UseclicksyncCheckBox.Value = true;
            app.ClicksyncchanDropDownLabel = uilabel(app.SweepSetupPanel);
            app.ClicksyncchanDropDownLabel.Text = 'Click sync chan';
            app.ClicksyncchanDropDown = uidropdown(app.SweepSetupPanel);
            app.sLabel_10 = uilabel(app.SweepSetupPanel);
            app.sLabel_10.Text = 's';
            app.TClickgapLabel = uilabel(app.SweepSetupPanel);
            app.TClickgapLabel.Text = 'T Click gap';
            app.Sweep_Tclickgap = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Sweep_Tclickgap.Limits = [0.1 60];
            app.Sweep_Tclickgap.ValueDisplayFormat = '%.2f';
            app.Sweep_Tclickgap.Value = 1;
            app.averagesEditFieldLabel = uilabel(app.SweepSetupPanel);
            app.averagesEditFieldLabel.Text = '# averages';
            app.NSweepAveragesEditField = uieditfield(app.SweepSetupPanel, 'numeric');
            app.NSweepAveragesEditField.Limits = [1 60];
            app.NSweepAveragesEditField.ValueDisplayFormat = '%.0f';
            app.NSweepAveragesEditField.Editable = 'off';
            app.NSweepAveragesEditField.Value = 1;
            app.sLabel_11 = uilabel(app.SweepSetupPanel);
            app.sLabel_11.Text = 's';
            app.TrepgapLabel = uilabel(app.SweepSetupPanel);
            app.TrepgapLabel.Text = 'T rep gap';
            app.Sweep_Trep_gap = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Sweep_Trep_gap.Limits = [0.1 60];
            app.Sweep_Trep_gap.ValueDisplayFormat = '%.2f';
            app.Sweep_Trep_gap.Editable = 'off';
            app.Sweep_Trep_gap.Value = 1;
            app.UseKirkebyCheckBox = uicheckbox(app.SweepSetupPanel);
            app.UseKirkebyCheckBox.Text = 'Use Kirkeby reg?';
            app.UseKirkebyCheckBox.Value = true;
            app.HzLabel_6 = uilabel(app.SweepSetupPanel);
            app.HzLabel_6.Text = 'Hz';
            app.ftransitionwidthLabel = uilabel(app.SweepSetupPanel);
            app.ftransitionwidthLabel.Text = 'f (transition width)';
            app.Kirkeby_fTransition = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Kirkeby_fTransition.Limits = [1 1000];
            app.Kirkeby_fTransition.ValueDisplayFormat = '%.0f';
            app.Kirkeby_fTransition.Value = 15;
            app.dBLabel = uilabel(app.SweepSetupPanel);
            app.dBLabel.Text = 'dB';
            app.PassbandregularisationLabel = uilabel(app.SweepSetupPanel);
            app.PassbandregularisationLabel.Text = 'Passband regularisation';
            app.Kirkeby_Passband_dB = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Kirkeby_Passband_dB.ValueDisplayFormat = '%.0f';
            app.Kirkeby_Passband_dB.Value = -Inf;
            app.dBLabel_2 = uilabel(app.SweepSetupPanel);
            app.dBLabel_2.Text = 'dB';
            app.StopbandregularisationLabel = uilabel(app.SweepSetupPanel);
            app.StopbandregularisationLabel.Text = 'Stopband regularisation';
            app.Kirkeby_Stopband_dB = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Kirkeby_Stopband_dB.ValueDisplayFormat = '%.0f';
            app.Kirkeby_Stopband_dB.Value = 10;
            app.Label = uilabel(app.SweepSetupPanel);
            app.Label.Text = '';
            app.dBFSLabel = uilabel(app.SweepSetupPanel);
            app.dBFSLabel.Text = 'dBFS';
            app.VolumeLabel = uilabel(app.SweepSetupPanel);
            app.VolumeLabel.Text = 'Volume';
            app.Sweep_Volume = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Sweep_Volume.Limits = [-Inf 0];
            app.Sweep_Volume.ValueDisplayFormat = '%.0f';
            app.Sweep_Volume.Value = -10;
            app.dBFSLabel_2 = uilabel(app.SweepSetupPanel);
            app.dBFSLabel_2.Text = 'dBFS';
            app.ClickVolLabel = uilabel(app.SweepSetupPanel);
            app.ClickVolLabel.Text = 'Click Vol';
            app.Click_Volume = uieditfield(app.SweepSetupPanel, 'numeric');
            app.Click_Volume.Limits = [-Inf 0];
            app.Click_Volume.ValueDisplayFormat = '%.0f';
            app.Click_Volume.Value = -10;
            app.TRIRdurationLabel_2 = uilabel(app.SweepSetupPanel);
            app.TRIRdurationLabel_2.Text = 'T RIR duration';
            app.T_RIR_Duration_Main = uieditfield(app.SweepSetupPanel, 'numeric');
            app.T_RIR_Duration_Main.Limits = [0.5 120];
            app.T_RIR_Duration_Main.ValueDisplayFormat = '%.2f';
            app.T_RIR_Duration_Main.ValueChangedFcn = createCallbackFcn(app, @T_RIR_Duration_MainValueChanged, true);
            app.T_RIR_Duration_Main.Value = 5;
            app.sLabel_13 = uilabel(app.SweepSetupPanel);
            app.sLabel_13.Text = 's';
            app.DataManagePanel = uipanel(app.MikIRAMMeasureTab);
            app.DataManagePanel.Title = 'Data management & export; figure creation';
            app.MaindatafolderEditFieldLabel = uilabel(app.DataManagePanel);
            app.MaindatafolderEditFieldLabel.Text = 'Main data folder';
            app.MaindatafolderEditField = uieditfield(app.DataManagePanel, 'text');
            app.MaindatafolderEditField.Value = 'Data_RIR_2018';
            app.sLabel_7 = uilabel(app.DataManagePanel);
            app.sLabel_7.Text = 's';
            app.TRIRexportdurationLabel = uilabel(app.DataManagePanel);
            app.TRIRexportdurationLabel.Text = 'T RIR export duration';
            app.T_RIR_Duration_Export = uieditfield(app.DataManagePanel, 'numeric');
            app.T_RIR_Duration_Export.Limits = [0.001 120];
            app.T_RIR_Duration_Export.ValueDisplayFormat = '%.4f';
            app.T_RIR_Duration_Export.ValueChangedFcn = createCallbackFcn(app, @T_RIR_Duration_ExportValueChanged, true);
            app.T_RIR_Duration_Export.Value = 5;
            app.sLabel_12 = uilabel(app.DataManagePanel);
            app.sLabel_12.Text = 's';
            app.TRIRprecutLabel = uilabel(app.DataManagePanel);
            app.TRIRprecutLabel.Text = 'T RIR pre-cut';
            app.T_RIR_PreCut_DurationBox = uieditfield(app.DataManagePanel, 'numeric');
            app.T_RIR_PreCut_DurationBox.Limits = [0 120];
            app.T_RIR_PreCut_DurationBox.ValueDisplayFormat = '%.4f';
            app.T_RIR_PreCut_DurationBox.Value = 0.002;
            app.ReplotRIRsButton = uibutton(app.DataManagePanel, 'push');
            app.ReplotRIRsButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotRIRsButtonPushed, true);
            app.ReplotRIRsButton.Text = 'Replot RIRs';
            app.ExportdatafileButton = uibutton(app.DataManagePanel, 'push');
            app.ExportdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdatafileButtonPushed, true);
            app.ExportdatafileButton.Text = 'Export data file';
            app.RetainAllDataCheckBox = uicheckbox(app.DataManagePanel);
            app.RetainAllDataCheckBox.Enable = 'off';
            app.RetainAllDataCheckBox.Text = 'Retain intermediate data?';
            app.muchlargerfilesLabel = uilabel(app.DataManagePanel);
            app.muchlargerfilesLabel.Enable = 'off';
            app.muchlargerfilesLabel.Text = '(much larger files!)';
            app.CustomExportPathCheckBox = uicheckbox(app.DataManagePanel);
            app.CustomExportPathCheckBox.Text = 'Custom folder path?';
            app.PassdatatoMikRIRPlotterButton = uibutton(app.DataManagePanel, 'push');
            app.PassdatatoMikRIRPlotterButton.ButtonPushedFcn = createCallbackFcn(app, @PassdatatoMikRIRPlotterButtonPushed, true);
            app.PassdatatoMikRIRPlotterButton.Text = 'Pass data to MikRIR Plotter';
            app.MeasAxes_Sweeps = uiaxes(app.MikIRAMMeasureTab);
            title(app.MeasAxes_Sweeps, 'Recorded mic signals (check for clipping!)')
            xlabel(app.MeasAxes_Sweeps, 'Time (s)')
            ylabel(app.MeasAxes_Sweeps, 'Amplitude')
            app.MeasurementPanel = uipanel(app.MikIRAMMeasureTab);
            app.MeasurementPanel.Title = 'Measurement control';
            app.MeasureRIRButton = uibutton(app.MeasurementPanel, 'push');
            app.MeasureRIRButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureRIRButtonPushed, true);
            app.MeasureRIRButton.Text = 'Measure RIR';
            app.MeasurementliveLampLabel = uilabel(app.MeasurementPanel);
            app.MeasurementliveLampLabel.Text = 'Measurement live?';
            app.MeasurementliveLamp = uilamp(app.MeasurementPanel);
            app.MeasurementliveLamp.Enable = 'off';
            app.PlotDiagnosticsCheckBox = uicheckbox(app.MeasurementPanel);
            app.PlotDiagnosticsCheckBox.Enable = 'off';
            app.PlotDiagnosticsCheckBox.Text = 'Plot diagnostics?';
            app.DataacquiredLabel = uilabel(app.MeasurementPanel);
            app.DataacquiredLabel.Text = 'Data acquired?';
            app.DataacquiredLamp = uilamp(app.MeasurementPanel);
            app.DataacquiredLamp.Enable = 'off';
            app.MicaudiosignalplotingPanel = uipanel(app.MikIRAMMeasureTab);
            app.MicaudiosignalplotingPanel.Title = 'Mic/audio signal ploting';
            app.PanSwitch_3Label = uilabel(app.MicaudiosignalplotingPanel);
            app.PanSwitch_3Label.Text = 'Pan';
            app.PanSwitchMics = uiswitch(app.MicaudiosignalplotingPanel, 'slider');
            app.PanSwitchMics.ValueChangedFcn = createCallbackFcn(app, @PanSwitchMicsValueChanged, true);
            app.ZoomSwitch_3Label = uilabel(app.MicaudiosignalplotingPanel);
            app.ZoomSwitch_3Label.Text = 'Zoom';
            app.ZoomSwitchMics = uiswitch(app.MicaudiosignalplotingPanel, 'slider');
            app.ZoomSwitchMics.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitchMicsValueChanged, true);
            app.ResetviewButtonMics = uibutton(app.MicaudiosignalplotingPanel, 'push');
            app.ResetviewButtonMics.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonMicsPushed, true);
            app.ResetviewButtonMics.Text = 'Reset view';
            app.tendEditField_2Label = uilabel(app.MicaudiosignalplotingPanel);
            app.tendEditField_2Label.Text = 't (end)';
            app.tendEditField_2 = uieditfield(app.MicaudiosignalplotingPanel, 'numeric');
            app.tendEditField_2.Limits = [0 Inf];
            app.tendEditField_2.ValueDisplayFormat = '%.2f';
            app.tendEditField_2.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes, true);
            app.tendEditField_2.Value = 2;
            app.AmplEditFieldLabel = uilabel(app.MicaudiosignalplotingPanel);
            app.AmplEditFieldLabel.Text = 'Ampl (+)';
            app.AmplEditField = uieditfield(app.MicaudiosignalplotingPanel, 'numeric');
            app.AmplEditField.Limits = [0 Inf];
            app.AmplEditField.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes, true);
            app.AmplEditField.Value = 1;
            app.AmplEditField_2Label = uilabel(app.MicaudiosignalplotingPanel);
            app.AmplEditField_2Label.Text = 'Ampl (-)';
            app.AmplEditField_2 = uieditfield(app.MicaudiosignalplotingPanel, 'numeric');
            app.AmplEditField_2.Limits = [-Inf 0];
            app.AmplEditField_2.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes, true);
            app.AmplEditField_2.Value = -1;
            app.LinearLogamplCheckBox_2 = uicheckbox(app.MicaudiosignalplotingPanel);
            app.LinearLogamplCheckBox_2.Enable = 'off';
            app.LinearLogamplCheckBox_2.Text = 'Linear/Log ampl';
            app.LinearLogtimeCheckBox_2 = uicheckbox(app.MicaudiosignalplotingPanel);
            app.LinearLogtimeCheckBox_2.Enable = 'off';
            app.LinearLogtimeCheckBox_2.Text = 'Linear/Log time';
            app.tstartEditField_2Label = uilabel(app.MicaudiosignalplotingPanel);
            app.tstartEditField_2Label.Text = 't (start)';
            app.tstartEditField_2 = uieditfield(app.MicaudiosignalplotingPanel, 'numeric');
            app.tstartEditField_2.Limits = [0 Inf];
            app.tstartEditField_2.ValueDisplayFormat = '%.2f';
            app.tstartEditField_2.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes, true);
            app.MeasAxes_RIR = uiaxes(app.MikIRAMMeasureTab);
            title(app.MeasAxes_RIR, 'Final windowed room impulse response(s)')
            xlabel(app.MeasAxes_RIR, 'Time (s)')
            ylabel(app.MeasAxes_RIR, 'Amplitude')
            app.FinalRIRplotingPanel = uipanel(app.MikIRAMMeasureTab);
            app.FinalRIRplotingPanel.Title = 'Final RIR ploting';
            app.PanSwitch_3Label_2 = uilabel(app.FinalRIRplotingPanel);
            app.PanSwitch_3Label_2.Text = 'Pan';
            app.PanSwitchRIRsMeas = uiswitch(app.FinalRIRplotingPanel, 'slider');
            app.PanSwitchRIRsMeas.ValueChangedFcn = createCallbackFcn(app, @PanSwitchRIRsMeasValueChanged, true);
            app.ZoomSwitch_3Label_2 = uilabel(app.FinalRIRplotingPanel);
            app.ZoomSwitch_3Label_2.Text = 'Zoom';
            app.ZoomSwitchRIRsMeas = uiswitch(app.FinalRIRplotingPanel, 'slider');
            app.ZoomSwitchRIRsMeas.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitchRIRsMeasValueChanged, true);
            app.ResetviewButtonRIRMeas = uibutton(app.FinalRIRplotingPanel, 'push');
            app.ResetviewButtonRIRMeas.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonRIRMeasPushed, true);
            app.ResetviewButtonRIRMeas.Text = 'Reset view';
            app.tendEditField_3Label = uilabel(app.FinalRIRplotingPanel);
            app.tendEditField_3Label.Text = 't (end)';
            app.tendEditField_RIR = uieditfield(app.FinalRIRplotingPanel, 'numeric');
            app.tendEditField_RIR.Limits = [0 Inf];
            app.tendEditField_RIR.ValueDisplayFormat = '%.2f';
            app.tendEditField_RIR.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes_RIR_Final, true);
            app.tendEditField_RIR.Value = 5;
            app.AmplEditField_3Label = uilabel(app.FinalRIRplotingPanel);
            app.AmplEditField_3Label.Text = 'Ampl (+)';
            app.AmplEditFieldHigh_RIR = uieditfield(app.FinalRIRplotingPanel, 'numeric');
            app.AmplEditFieldHigh_RIR.Limits = [0 Inf];
            app.AmplEditFieldHigh_RIR.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes_RIR_Final, true);
            app.AmplEditFieldHigh_RIR.Value = 1;
            app.AmplEditField_4Label = uilabel(app.FinalRIRplotingPanel);
            app.AmplEditField_4Label.Text = 'Ampl (-)';
            app.AmplEditFieldLow_RIR = uieditfield(app.FinalRIRplotingPanel, 'numeric');
            app.AmplEditFieldLow_RIR.Limits = [-Inf 0];
            app.AmplEditFieldLow_RIR.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes_RIR_Final, true);
            app.AmplEditFieldLow_RIR.Value = -1;
            app.LinearLogamplCheckBox_3 = uicheckbox(app.FinalRIRplotingPanel);
            app.LinearLogamplCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @LinearLogamplCheckBox_3ValueChanged, true);
            app.LinearLogamplCheckBox_3.Text = 'Linear/Log ampl';
            app.LinearLogtimeCheckBox_3 = uicheckbox(app.FinalRIRplotingPanel);
            app.LinearLogtimeCheckBox_3.Enable = 'off';
            app.LinearLogtimeCheckBox_3.Text = 'Linear/Log time';
            app.tstartEditField_3Label = uilabel(app.FinalRIRplotingPanel);
            app.tstartEditField_3Label.Text = 't (start)';
            app.tstartEditField_RIR = uieditfield(app.FinalRIRplotingPanel, 'numeric');
            app.tstartEditField_RIR.Limits = [0 Inf];
            app.tstartEditField_RIR.ValueDisplayFormat = '%.2f';
            app.tstartEditField_RIR.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes_RIR_Final, true);
            app.MeasAxes_RIR_RAW = uiaxes(app.MikIRAMMeasureTab);
            title(app.MeasAxes_RIR_RAW, 'Raw room impulse response(s) (immediately post deconvolution)')
            xlabel(app.MeasAxes_RIR_RAW, 'Time (s)')
            ylabel(app.MeasAxes_RIR_RAW, 'Amplitude')
            app.RawRIRplotingPanel = uipanel(app.MikIRAMMeasureTab);
            app.RawRIRplotingPanel.Title = 'Raw RIR ploting';
            app.PanSwitch_3Label_3 = uilabel(app.RawRIRplotingPanel);
            app.PanSwitch_3Label_3.Text = 'Pan';
            app.PanSwitchRIRsMeas_RAW = uiswitch(app.RawRIRplotingPanel, 'slider');
            app.PanSwitchRIRsMeas_RAW.ValueChangedFcn = createCallbackFcn(app, @PanSwitchRIRsMeas_RAWValueChanged, true);
            app.ZoomSwitch_3Label_3 = uilabel(app.RawRIRplotingPanel);
            app.ZoomSwitch_3Label_3.Text = 'Zoom';
            app.ZoomSwitchRIRsMeas_RAW = uiswitch(app.RawRIRplotingPanel, 'slider');
            app.ZoomSwitchRIRsMeas_RAW.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitchRIRsMeas_RAWValueChanged, true);
            app.ResetviewButtonRIRMeas_RAW = uibutton(app.RawRIRplotingPanel, 'push');
            app.ResetviewButtonRIRMeas_RAW.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonRIRMeas_RAWPushed, true);
            app.ResetviewButtonRIRMeas_RAW.Text = 'Reset view';
            app.tendEditField_4Label = uilabel(app.RawRIRplotingPanel);
            app.tendEditField_4Label.Text = 't (end)';
            app.tendEditField_RIR_RAW = uieditfield(app.RawRIRplotingPanel, 'numeric');
            app.tendEditField_RIR_RAW.Limits = [0 Inf];
            app.tendEditField_RIR_RAW.ValueDisplayFormat = '%.2f';
            app.tendEditField_RIR_RAW.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes_RIR_RAW, true);
            app.tendEditField_RIR_RAW.Value = 30;
            app.AmplEditField_5Label = uilabel(app.RawRIRplotingPanel);
            app.AmplEditField_5Label.Text = 'Ampl (+)';
            app.AmplEditFieldHigh_RIR_RAW = uieditfield(app.RawRIRplotingPanel, 'numeric');
            app.AmplEditFieldHigh_RIR_RAW.Limits = [0 Inf];
            app.AmplEditFieldHigh_RIR_RAW.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes_RIR_RAW, true);
            app.AmplEditFieldHigh_RIR_RAW.Value = 1;
            app.AmplEditField_6Label = uilabel(app.RawRIRplotingPanel);
            app.AmplEditField_6Label.Text = 'Ampl (-)';
            app.AmplEditFieldLow_RIR_RAW = uieditfield(app.RawRIRplotingPanel, 'numeric');
            app.AmplEditFieldLow_RIR_RAW.Limits = [-Inf 0];
            app.AmplEditFieldLow_RIR_RAW.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes_RIR_RAW, true);
            app.AmplEditFieldLow_RIR_RAW.Value = -1;
            app.LinearLogamplCheckBox_RIR_RAW = uicheckbox(app.RawRIRplotingPanel);
            app.LinearLogamplCheckBox_RIR_RAW.Enable = 'off';
            app.LinearLogamplCheckBox_RIR_RAW.Text = 'Linear/Log ampl';
            app.LinearLogtimeCheckBox_RIR_RAW = uicheckbox(app.RawRIRplotingPanel);
            app.LinearLogtimeCheckBox_RIR_RAW.Enable = 'off';
            app.LinearLogtimeCheckBox_RIR_RAW.Text = 'Linear/Log time';
            app.tstartEditField_4Label = uilabel(app.RawRIRplotingPanel);
            app.tstartEditField_4Label.Text = 't (start)';
            app.tstartEditField_RIR_RAW = uieditfield(app.RawRIRplotingPanel, 'numeric');
            app.tstartEditField_RIR_RAW.Limits = [0 Inf];
            app.tstartEditField_RIR_RAW.ValueDisplayFormat = '%.2f';
            app.tstartEditField_RIR_RAW.ValueChangedFcn = createCallbackFcn(app, @Mics_setAxes_RIR_RAW, true);
            app.MikIRAMPlotterTab = uitab(app.TabGroup);
            app.MikIRAMPlotterTab.Title = 'MikIRAM Plotter';
            app.Axes_IR = uiaxes(app.MikIRAMPlotterTab);
            title(app.Axes_IR, 'Room Impulse Response(s)')
            xlabel(app.Axes_IR, 'Time (s)')
            ylabel(app.Axes_IR, 'Amplitude (raw)')
            app.Axes_IR.XLim = [0 1];
            app.Axes_IR.YLim = [0 1];
            app.Axes_IR.ZLim = [0 1];
            app.Axes_IR.CLim = [0 1];
            app.Axes_IR.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Axes_IR.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Axes_IR.ZTick = [0 0.5 1];
            app.RIRPlotControlsPanel = uipanel(app.MikIRAMPlotterTab);
            app.RIRPlotControlsPanel.Title = 'RIR Plot Controls';
            app.PanSwitchLabel = uilabel(app.RIRPlotControlsPanel);
            app.PanSwitchLabel.Text = 'Pan';
            app.PanSwitch = uiswitch(app.RIRPlotControlsPanel, 'slider');
            app.PanSwitch.ValueChangedFcn = createCallbackFcn(app, @PanSwitchValueChanged, true);
            app.ZoomSwitchLabel = uilabel(app.RIRPlotControlsPanel);
            app.ZoomSwitchLabel.Text = 'Zoom';
            app.ZoomSwitch = uiswitch(app.RIRPlotControlsPanel, 'slider');
            app.ZoomSwitch.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitchValueChanged, true);
            app.ResetviewButton = uibutton(app.RIRPlotControlsPanel, 'push');
            app.ResetviewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonPushed, true);
            app.ResetviewButton.Text = 'Reset view';
            app.tstartEditFieldLabel = uilabel(app.RIRPlotControlsPanel);
            app.tstartEditFieldLabel.Text = 't (start)';
            app.tstartEditField = uieditfield(app.RIRPlotControlsPanel, 'numeric');
            app.tstartEditField.Limits = [0 Inf];
            app.tstartEditField.ValueDisplayFormat = '%.4f';
            app.tstartEditField.ValueChangedFcn = createCallbackFcn(app, @RIR_setAxes, true);
            app.tendEditFieldLabel = uilabel(app.RIRPlotControlsPanel);
            app.tendEditFieldLabel.Text = 't (end)';
            app.tendEditField = uieditfield(app.RIRPlotControlsPanel, 'numeric');
            app.tendEditField.Limits = [0 Inf];
            app.tendEditField.ValueDisplayFormat = '%.4f';
            app.tendEditField.ValueChangedFcn = createCallbackFcn(app, @RIR_setAxes, true);
            app.tendEditField.Value = 2;
            app.AmplhighEditFieldLabel = uilabel(app.RIRPlotControlsPanel);
            app.AmplhighEditFieldLabel.Text = 'Ampl (high)';
            app.AmplhighEditField = uieditfield(app.RIRPlotControlsPanel, 'numeric');
            app.AmplhighEditField.Limits = [0 Inf];
            app.AmplhighEditField.ValueChangedFcn = createCallbackFcn(app, @RIR_setAxes, true);
            app.AmplhighEditField.Value = 1;
            app.AmpllowEditFieldLabel = uilabel(app.RIRPlotControlsPanel);
            app.AmpllowEditFieldLabel.Text = 'Ampl (low)';
            app.AmpllowEditField = uieditfield(app.RIRPlotControlsPanel, 'numeric');
            app.AmpllowEditField.Limits = [-Inf 0];
            app.AmpllowEditField.ValueChangedFcn = createCallbackFcn(app, @RIR_setAxes, true);
            app.AmpllowEditField.Value = -1;
            app.sLabel = uilabel(app.RIRPlotControlsPanel);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.RIRPlotControlsPanel);
            app.sLabel_2.Text = 's';
            app.LinearLogamplCheckBox = uicheckbox(app.RIRPlotControlsPanel);
            app.LinearLogamplCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearLogamplCheckBoxValueChanged, true);
            app.LinearLogamplCheckBox.Text = 'Linear/Log ampl';
            app.LeaveAxes_RIRCheckBox = uicheckbox(app.RIRPlotControlsPanel);
            app.LeaveAxes_RIRCheckBox.Text = 'Leave axes alone';
            app.LinearLogtimeCheckBox = uicheckbox(app.RIRPlotControlsPanel);
            app.LinearLogtimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotdataButtonPushed, true);
            app.LinearLogtimeCheckBox.Text = 'Linear/Log time';
            app.NormaliseRIRCheckBox = uicheckbox(app.RIRPlotControlsPanel);
            app.NormaliseRIRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotdataButtonPushed, true);
            app.NormaliseRIRCheckBox.Text = 'Normalise all to 0dB';
            app.TabFiles = uitable(app.MikIRAMPlotterTab);
            app.TabFiles.ColumnName = {'File #'; 'Room Name'; 'Source Loc'; 'Receiver Loc'; 'Speaker ID'; 'Mic ID'; 'Run #'; 'Show?'; 'Curve ID'; 'Fs'};
            app.TabFiles.RowName = {};
            app.TabFiles.ColumnEditable = [false false false false false false false true false false];
            app.TabFiles.CellEditCallback = createCallbackFcn(app, @TabFilesCellEdit, true);
            app.TabFiles.CellSelectionCallback = createCallbackFcn(app, @TabFilesCellSelection, true);
            app.Axes_FR = uiaxes(app.MikIRAMPlotterTab);
            title(app.Axes_FR, 'Room Frequency Response(s)')
            xlabel(app.Axes_FR, 'Frequency (Hz)')
            ylabel(app.Axes_FR, 'Magnitude (dB)')
            app.Axes_FR.XLim = [0 1];
            app.Axes_FR.YLim = [0 1];
            app.Axes_FR.ZLim = [0 1];
            app.Axes_FR.CLim = [0 1];
            app.Axes_FR.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Axes_FR.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Axes_FR.ZTick = [0 0.5 1];
            app.RFRPlotControlsPanel = uipanel(app.MikIRAMPlotterTab);
            app.RFRPlotControlsPanel.Title = 'RFR Plot Controls';
            app.PanSwitch_2Label = uilabel(app.RFRPlotControlsPanel);
            app.PanSwitch_2Label.Text = 'Pan';
            app.PanSwitch_2 = uiswitch(app.RFRPlotControlsPanel, 'slider');
            app.PanSwitch_2.ValueChangedFcn = createCallbackFcn(app, @PanSwitch_2ValueChanged, true);
            app.ZoomSwitch_2Label = uilabel(app.RFRPlotControlsPanel);
            app.ZoomSwitch_2Label.Text = 'Zoom';
            app.ZoomSwitch_2 = uiswitch(app.RFRPlotControlsPanel, 'slider');
            app.ZoomSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ZoomSwitch_2ValueChanged, true);
            app.ResetviewButton_2 = uibutton(app.RFRPlotControlsPanel, 'push');
            app.ResetviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButton_2Pushed, true);
            app.ResetviewButton_2.Text = 'Reset view';
            app.flowEditFieldLabel = uilabel(app.RFRPlotControlsPanel);
            app.flowEditFieldLabel.Text = 'f (low)';
            app.flowEditField = uieditfield(app.RFRPlotControlsPanel, 'numeric');
            app.flowEditField.Limits = [0 Inf];
            app.flowEditField.ValueDisplayFormat = '%.0f';
            app.flowEditField.ValueChangedFcn = createCallbackFcn(app, @RFR_setAxes, true);
            app.flowEditField.Value = 40;
            app.fhighEditFieldLabel = uilabel(app.RFRPlotControlsPanel);
            app.fhighEditFieldLabel.Text = 'f (high)';
            app.fhighEditField = uieditfield(app.RFRPlotControlsPanel, 'numeric');
            app.fhighEditField.Limits = [0 Inf];
            app.fhighEditField.ValueDisplayFormat = '%.0f';
            app.fhighEditField.ValueChangedFcn = createCallbackFcn(app, @RFR_setAxes, true);
            app.fhighEditField.Value = 20000;
            app.HzLabel = uilabel(app.RFRPlotControlsPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.RFRPlotControlsPanel);
            app.HzLabel_2.Text = 'Hz';
            app.dBhighEditFieldLabel = uilabel(app.RFRPlotControlsPanel);
            app.dBhighEditFieldLabel.Text = 'dB (high)';
            app.dBhighEditField = uieditfield(app.RFRPlotControlsPanel, 'numeric');
            app.dBhighEditField.Limits = [0 Inf];
            app.dBhighEditField.ValueChangedFcn = createCallbackFcn(app, @RFR_setAxes, true);
            app.dBlowEditFieldLabel = uilabel(app.RFRPlotControlsPanel);
            app.dBlowEditFieldLabel.Text = 'dB (low)';
            app.dBlowEditField = uieditfield(app.RFRPlotControlsPanel, 'numeric');
            app.dBlowEditField.Limits = [-Inf 0];
            app.dBlowEditField.ValueChangedFcn = createCallbackFcn(app, @RFR_setAxes, true);
            app.dBlowEditField.Value = -60;
            app.LinearLogfreqCheckBox = uicheckbox(app.RFRPlotControlsPanel);
            app.LinearLogfreqCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotdataButtonPushed, true);
            app.LinearLogfreqCheckBox.Text = 'Linear/Log freq';
            app.LinearLogfreqCheckBox.Value = true;
            app.LeaveAxes_RFRCheckBox = uicheckbox(app.RFRPlotControlsPanel);
            app.LeaveAxes_RFRCheckBox.Text = 'Leave axes alone';
            app.LinearLogmagCheckBox = uicheckbox(app.RFRPlotControlsPanel);
            app.LinearLogmagCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearLogmagCheckBoxValueChanged, true);
            app.LinearLogmagCheckBox.Text = 'Linear/Log mag';
            app.LinearLogmagCheckBox.Value = true;
            app.NormaliseRFRCheckBox = uicheckbox(app.RFRPlotControlsPanel);
            app.NormaliseRFRCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotdataButtonPushed, true);
            app.NormaliseRFRCheckBox.Text = 'Normalise all to 0dB';
            app.LoadMikRIRdataMATButton = uibutton(app.MikIRAMPlotterTab, 'push');
            app.LoadMikRIRdataMATButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMikRIRdataMATButtonPushed, true);
            app.LoadMikRIRdataMATButton.Text = 'Load MikRIR data (.MAT)';
            app.PlotdataButton = uibutton(app.MikIRAMPlotterTab, 'push');
            app.PlotdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdataButtonPushed, true);
            app.PlotdataButton.Text = 'Plot data';
            app.DeselectallButton = uibutton(app.MikIRAMPlotterTab, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.SelectallButton = uibutton(app.MikIRAMPlotterTab, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.AudioPlaybackPanel = uipanel(app.MikIRAMPlotterTab);
            app.AudioPlaybackPanel.Title = 'Audio Playback';
            app.PlayRIRaudioDropDownLabel = uilabel(app.AudioPlaybackPanel);
            app.PlayRIRaudioDropDownLabel.Text = 'Play RIR audio';
            app.PlayRIRaudioDropDown = uidropdown(app.AudioPlaybackPanel);
            app.PlayButton = uibutton(app.AudioPlaybackPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play!';
            app.sLabel_3 = uilabel(app.AudioPlaybackPanel);
            app.sLabel_3.Text = 's';
            app.PlaydurationEditFieldLabel = uilabel(app.AudioPlaybackPanel);
            app.PlaydurationEditFieldLabel.Text = 'Play duration';
            app.PlaydurationEditField = uieditfield(app.AudioPlaybackPanel, 'numeric');
            app.PlaydurationEditField.ValueDisplayFormat = '%.2f';
            app.PlaydurationEditField.Value = 3;
            app.AudioplayingLabel = uilabel(app.AudioPlaybackPanel);
            app.AudioplayingLabel.Text = 'Audio playing?';
            app.AudioplayingLamp = uilamp(app.AudioPlaybackPanel);
            app.AudioplayingLamp.Enable = 'off';
            app.RIRExportPanel = uipanel(app.MikIRAMPlotterTab);
            app.RIRExportPanel.Title = 'RIR Export - write selected RIRs as .WAV';
            app.FsNoticeText = uilabel(app.RIRExportPanel);
            app.FsNoticeText.Text = '(All curves need same sample rate Fs!)';
            app.StatusTextAreaLabel = uilabel(app.RIRExportPanel);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.RIRExportPanel);
            app.ExportWAVButton = uibutton(app.RIRExportPanel, 'push');
            app.ExportWAVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportWAVButtonPushed, true);
            app.ExportWAVButton.Text = 'Export WAV ';
            app.NormEachCurveCheckBox = uicheckbox(app.RIRExportPanel);
            app.NormEachCurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormEachCurveCheckBoxValueChanged, true);
            app.NormEachCurveCheckBox.Text = 'Norm RIRs to 0.1dBFS (ignores relative levels)';
            app.NormPeakCurveCheckBox = uicheckbox(app.RIRExportPanel);
            app.NormPeakCurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormPeakCurveCheckBoxValueChanged, true);
            app.NormPeakCurveCheckBox.Text = 'Norm loudest RIR to 0.1dBFS (keeps relative levs)';
            app.NormPeakCurveCheckBox.Value = true;
            app.sLabel_4 = uilabel(app.RIRExportPanel);
            app.sLabel_4.Text = 's';
            app.TruncateIRCheckBox = uicheckbox(app.RIRExportPanel);
            app.TruncateIRCheckBox.ValueChangedFcn = createCallbackFcn(app, @TruncateIRCheckBoxValueChanged, true);
            app.TruncateIRCheckBox.Text = 'Truncate IR?';
            app.sLabel_5 = uilabel(app.RIRExportPanel);
            app.sLabel_5.Text = 's';
            app.RIRdurationEditFieldLabel = uilabel(app.RIRExportPanel);
            app.RIRdurationEditFieldLabel.Enable = 'off';
            app.RIRdurationEditFieldLabel.Text = 'RIR duration';
            app.RIRdurationEditField = uieditfield(app.RIRExportPanel, 'numeric');
            app.RIRdurationEditField.Limits = [0.01 Inf];
            app.RIRdurationEditField.ValueDisplayFormat = '%.2f';
            app.RIRdurationEditField.Enable = 'off';
            app.RIRdurationEditField.Value = 5;
            app.RIRTstartEditFieldLabel = uilabel(app.RIRExportPanel);
            app.RIRTstartEditFieldLabel.Enable = 'off';
            app.RIRTstartEditFieldLabel.Text = 'RIR T start';
            app.RIRTstartEditField = uieditfield(app.RIRExportPanel, 'numeric');
            app.RIRTstartEditField.Limits = [0 Inf];
            app.RIRTstartEditField.ValueDisplayFormat = '%.2f';
            app.RIRTstartEditField.Enable = 'off';
            app.DataLoadingSwitch = uiswitch(app.MikIRAMPlotterTab, 'toggle');
            app.DataLoadingSwitch.Items = {'New', 'Load'};
            app.DataLoadingSwitch.ItemsData = {'0', '1'};
            app.DataLoadingSwitch.ValueChangedFcn = createCallbackFcn(app, @DataLoadingSwitchValueChanged, true);
            app.DataLoadingSwitch.Value = '0';
            app.MikIRAMAnalysisTab = uitab(app.TabGroup);
            app.MikIRAMAnalysisTab.Title = 'MikIRAM Analysis';
            app.FeaturenotyetimplementedLabel_2 = uilabel(app.MikIRAMAnalysisTab);
            app.FeaturenotyetimplementedLabel_2.Text = 'Feature not yet implemented!';
            app.DetailedanalysisofasingleRIRLabel = uilabel(app.MikIRAMAnalysisTab);
            app.DetailedanalysisofasingleRIRLabel.Text = 'Detailed analysis of a single RIR';
            app.MeasAxes_CalibCurves = uiaxes(app.MikIRAMAnalysisTab);
            title(app.MeasAxes_CalibCurves, 'Calibration curves')
            xlabel(app.MeasAxes_CalibCurves, 'Frequency (Hz)')
            ylabel(app.MeasAxes_CalibCurves, 'Magnitude')
            app.CalibrationsetupoptionalnotyetimplementedPanel = uipanel(app.MikIRAMAnalysisTab);
            app.CalibrationsetupoptionalnotyetimplementedPanel.Title = 'Calibration setup (optional) - (not yet implemented)';
            app.LoadinterfacecalfileButton = uibutton(app.CalibrationsetupoptionalnotyetimplementedPanel, 'push');
            app.LoadinterfacecalfileButton.Text = 'Load interface cal file';
            app.CalibrationfileinfoTextAreaLabel = uilabel(app.CalibrationsetupoptionalnotyetimplementedPanel);
            app.CalibrationfileinfoTextAreaLabel.Text = 'Calibration file info:';
            app.CalibrationfileinfoTextArea = uitextarea(app.CalibrationsetupoptionalnotyetimplementedPanel);
            app.LoadspeakermiccalfileButton = uibutton(app.CalibrationsetupoptionalnotyetimplementedPanel, 'push');
            app.LoadspeakermiccalfileButton.Text = 'Load speaker/mic cal file';
            app.CalibrationfileinfoTextArea_2 = uitextarea(app.CalibrationsetupoptionalnotyetimplementedPanel);
            app.WAVFileAnalysisTab = uitab(app.TabGroup);
            app.WAVFileAnalysisTab.Title = 'WAV File Analysis';
            app.LoadRIRWAVfileButton = uibutton(app.WAVFileAnalysisTab, 'push');
            app.LoadRIRWAVfileButton.Text = 'Load RIR .WAV file';
            app.FeaturenotyetimplementedLabel = uilabel(app.WAVFileAnalysisTab);
            app.FeaturenotyetimplementedLabel.Text = 'Feature not yet implemented!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MikIRAM_V1a

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MikRIR_Plotting_Utility)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MikRIR_Plotting_Utility)
        end
    end
end